classdef BMS_FV < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        LiveTempsandVoltagesTab      matlab.ui.container.Tab
        GridLayout                   matlab.ui.container.GridLayout
        ControlsPanel                matlab.ui.container.Panel
        ChargeCurrentInputEditField  matlab.ui.control.NumericEditField
        ChargeCurrentInputEditFieldLabel  matlab.ui.control.Label
        BaudRateDropDown             matlab.ui.control.DropDown
        BaudRateDropDownLabel        matlab.ui.control.Label
        PortEditField                matlab.ui.control.EditField
        PortEditFieldLabel           matlab.ui.control.Label
        TESTButton                   matlab.ui.control.Button
        PrecisionCheckBox            matlab.ui.control.CheckBox
        ICMaxTempLabel               matlab.ui.control.Label
        MaxTIDLabel                  matlab.ui.control.Label
        ErrorLabel                   matlab.ui.control.Label
        MaxVIDLabel                  matlab.ui.control.Label
        TSCurrentLabel               matlab.ui.control.Label
        MaxTempLabel                 matlab.ui.control.Label
        MinTempLabel                 matlab.ui.control.Label
        DeltaVoltLabel               matlab.ui.control.Label
        AvgVoltLabel                 matlab.ui.control.Label
        MaxVoltLabel                 matlab.ui.control.Label
        MinVoltLabel                 matlab.ui.control.Label
        PackVoltLabel                matlab.ui.control.Label
        StatusLabel                  matlab.ui.control.Label
        BalanceCheckBox              matlab.ui.control.CheckBox
        ChargeButton                 matlab.ui.control.StateButton
        ChargerStatusLabel           matlab.ui.control.Label
        Image                        matlab.ui.control.Image
        LogtoCSVCheckBox             matlab.ui.control.CheckBox
        ConnectButton                matlab.ui.control.Button
        VoltagesPanel                matlab.ui.container.Panel
        SegmentAvgTable              matlab.ui.control.Table
        UITable3                     matlab.ui.control.Table
        TemepraturesPanel            matlab.ui.container.Panel
        ICTempTable                  matlab.ui.control.Table
        UITable2                     matlab.ui.control.Table
    end

    
    properties (Access = private)
        % Data Storage (12x12 for voltages, 12x12 for temps but only using specific columns)
        VoltageList double = -1 * ones(12,12)
        TempsList double   = -1 * ones(12,12)  % Changed from 12x10 to 12x12
        MAXICTemps double = NaN(1,12)  % IC temperature readings
        
        % Telemetry
        ts_current double = -1
        charging_current double = -1
        pack_voltage double = -1
        charger_connected logical = false
        charger_status_flag double = -1
        ivt_current double = 0  % mA from IVT sensor
        mainLoopCounter double = 0
        % Serial + updates
        sp = []
        isConnected logical = false
    
        % Charging Control
        chargingState double = 0  % 0=OFF, 1=ON
        chargeHexCode char = '64'  % Default current code for CAN
        
        % Safety Thresholds
        MAX_VOLTAGE_THRESHOLD double = 4.15  % V
        MIN_VOLTAGE_THRESHOLD double = 2.7   % V
        TEMP_WARN_LIMIT double = 59          % °C
    
        % Logging
        logEnabled logical = false
        logFID double = -1
        logFile string = "bms_log.csv"
        csvHeaderWritten logical = false
        t0 double = 0  % Timestamp base
    
        % UI refresh
        MainTimer timer
        heartbeatTimer timer  % For sending heartbeat to BMS
        isClosing logical = false
        lastRxTime double = 0
        CONNECTION_TIMEOUT double = 5.0
        
        % Display Settings
        showExtraPrecision logical = false
        
        % Error Tracking
        lastError char = 'None'
        
        % Connections
        connectedIcon   string = 'connected.png';
        disconnectedIcon string = 'disconnected.png';
        connectingIcon  string = 'connecting.jpg';


        chargerMode logical = false
    end
    
    properties (Access = public)
        Property16 % Description
    end


    methods (Access = private)
        % Button pushed function: TESTButton
        function TESTButtonPushed(app,~)
            injectTestData(app);
        end
        
       function initTables(app)
    % Voltages table (UITable3): 12x12
    app.UITable3.ColumnName = arrayfun(@(k)sprintf('V%d',k), 1:12, 'UniformOutput', false);
    app.UITable3.RowName    = arrayfun(@(k)sprintf('S%d',k), 1:12, 'UniformOutput', false);
    app.UITable3.Data       = repmat({ '-- V' }, 12, 12);

    % Temps table (UITable2): Display only T2,T3,T4,T5,T6,T7,T8,T9,T10,T12
    app.UITable2.ColumnName = {'T2', 'T3', 'T4', 'T5', 'T6', 'T7', 'T8', 'T9', 'T10', 'T12'};
    app.UITable2.RowName    = arrayfun(@(k)sprintf('S%d',k), 1:12, 'UniformOutput', false);
    app.UITable2.Data       = repmat({ '-- °C' }, 12, 10);

    % ===== SEGMENTS TAB TABLES =====
    % ===== SEGMENTS TAB TABLES =====
    % Segment Average Voltages Table: 1 row x 12 columns (TOP TABLE)
    app.SegmentAvgTable.ColumnName = arrayfun(@(k)sprintf('Seg%d',k), 1:12, 'UniformOutput', false);  % CHANGED IC to Seg
    app.SegmentAvgTable.RowName = {'Avg Voltage'};
    app.SegmentAvgTable.Data = zeros(1, 12);
    app.SegmentAvgTable.ColumnWidth = '1x';
    
    % IC Temperature Table: 1 row x 12 columns (BOTTOM TABLE)
    app.ICTempTable.ColumnName = arrayfun(@(k)sprintf('IC%d',k), 1:12, 'UniformOutput', false);
    app.ICTempTable.RowName = {'IC Temp'};
    app.ICTempTable.Data = zeros(1, 12);
    app.ICTempTable.ColumnWidth = '1x';
end
    
       function connectPort(app)
            port = strtrim(app.PortEditField.Value);
            if isempty(port)
                uialert(app.UIFigure,'Enter a port.','No Port');
                return;
            end
        
            try
                fprintf('Attempting to connect to %s...\n', port);
                
                app.isConnected = false;
                app.isClosing = true;
                
                % Rapid Timer Cleanup
                try
                    timersToStop = [app.MainTimer, app.heartbeatTimer];
                    for t = timersToStop
                        if ~isempty(t) && isvalid(t)
                            stop(t);
                            delete(t);
                        end
                    end
                catch
                end
                
                % Clean up existing serial port
                if ~isempty(app.sp)
                    try
                        configureCallback(app.sp, "off"); % Disable any old callbacks
                        delete(app.sp);
                    catch
                    end
                    app.sp = [];
                end
                
                app.isClosing = false;
                
                % 3. Open serial port
                fprintf('Opening serial port...\n');
                baud_uart = 9600;  
                app.sp = serialport(port, baud_uart, 'Timeout', 0.5);
                configureTerminator(app.sp, "CR"); 
                flush(app.sp);
                
                % Initialize CANdapter
                try
                    writeline(app.sp, "C"); 
                    writeline(app.sp, "L1");
                catch
                end
                
                % Set CAN bitrate
                can_cmd = 'S6'; % Default 500kbit/s
                if isprop(app, 'BaudRateDropDown')
                    switch app.BaudRateDropDown.Value
                        case '125kbit/s', can_cmd = 'S4';
                        case '250kbit/s', can_cmd = 'S5';
                        case '500kbit/s', can_cmd = 'S6';
                        case '1000kbit/s', can_cmd = 'S8';
                    end
                end
                
                try
                    writeline(app.sp, can_cmd);
                    writeline(app.sp, "L1");
                    writeline(app.sp, "O");
                    writeline(app.sp, "L1");
                catch
                end
                
                % Send initial frame with balance flag
                balanceFlag = 0;
                if isprop(app, 'BalanceCheckBox')
                    balanceFlag = app.BalanceCheckBox.Value;
                end
                
                init = sprintf('T1502%s%d1', app.chargeHexCode, balanceFlag);
                try
                    writeline(app.sp, init);
                    writeline(app.sp, "L1");
                catch
                end
                
                % EVENT-DRIVEN SERIAL READING 
                % trigger 'readSerialData' in the background 
                % ONLY when a carriage return (CR) arrives.
                configureCallback(app.sp, "terminator", @(~,~)readSerialData(app));
                
                % Update state
                app.isConnected = true;
                app.lastRxTime = tic;  
                
                % GUI and Safety Timer 
                % Runs at 1 Hz to keep the UI snappy without overloading it.
                app.MainTimer = timer( ...
                    'Tag', 'BMS_Node_Timer', ...         
                    'ExecutionMode', 'fixedRate', ...
                    'Period', 1.0, ...
                    'TimerFcn', @(~,~)guiUpdateLoop(app));
                start(app.MainTimer);
                
                % Heartbeat timer
                app.heartbeatTimer = timer( ...
                    'Tag', 'BMS_Node_Timer', ...
                    'ExecutionMode', 'fixedRate', ...
                    'Period', 0.2, ...
                    'TimerFcn', @(~,~)sendHeartbeat(app));
                start(app.heartbeatTimer);
                
                % Update UI
                setConnectionUI(app, 'connected');
                
                if isprop(app, 'StatusLabel')
                    app.StatusLabel.Text = sprintf('Connected to %s — waiting for data...', port);
                    app.StatusLabel.FontColor = [0 0.8 0]; 
                end
                if isprop(app, 'ErrorLabel')
                    app.ErrorLabel.Text = 'Error: None';
                end
                applyChargerMode(app);
                fprintf('Connected successfully!\n');
                
            catch ME
                fprintf('Connection error: %s\n', ME.message);
                app.isConnected = false;
                app.isClosing = false;
                
                if ~isempty(app.sp)
                    try, delete(app.sp); catch, end
                    app.sp = [];
                end
                
                setConnectionUI(app, 'disconnected');
                if isprop(app, 'StatusLabel')
                    app.StatusLabel.Text = 'Connection Failed';
                    app.StatusLabel.FontColor = [1 0.2 0.2]; 
                end
                uialert(app.UIFigure, sprintf('Connection Failed: %s', ME.message), 'Connection Error');
            end
        end
        
        function disconnectPort(app)
            fprintf('Disconnecting...\n');
            app.isConnected = false;
            app.isClosing = true;  
        
            % Stop timers
            try
                timersToStop = [app.MainTimer, app.heartbeatTimer];
                for t = timersToStop
                    if ~isempty(t) && isvalid(t)
                        stop(t);
                        delete(t);
                    end
                end
            catch
            end
            
            % Close port and disable callback safely
            try
                if ~isempty(app.sp) && isvalid(app.sp)
                    configureCallback(app.sp, "off"); % Crucial to prevent background crashes
                    delete(app.sp);
                end
            catch ME
                fprintf('Serial close error: %s\n', ME.message);
            end
            app.sp = [];
            app.isClosing = false;
        
            setConnectionUI(app,'disconnected');
            if isprop(app, 'StatusLabel')
                app.StatusLabel.Text = 'Status: Disconnected';
                app.StatusLabel.FontColor = [1 0.2 0.2]; 
            end
            fprintf('Disconnected.\n');
        end

       function readSerialData(app)
    % Triggered automatically ONLY when a full line (CR) is ready.
    if app.isClosing || ~app.isConnected || isempty(app.sp) || ~isvalid(app.sp)
        return;
    end
    
    try
        % Read exactly one line. No loops, no blocking.
        line = strtrim(readline(app.sp));
        if ~isempty(line)
            app.lastRxTime = tic; 
            processLine(app, line);
        end
    catch ME
        % Silently catch errors so partial frames don't crash the parser
    end
end

        function guiUpdateLoop(app)
            % This function is triggered by the MainTimer at 1Hz.
            % It handles timeouts, safety, and GUI updates without blocking data flow.
            if app.isClosing || ~app.isConnected
                return;
            end
            
            try
                % Check for timeout
                if toc(app.lastRxTime) > app.CONNECTION_TIMEOUT
                    fprintf('Connection timeout!\n');
                    forceDisconnect(app, 'timeout');
                    return;
                end
                
                % Check Safety limits
                safetyCheck(app);
                
                % Update tables and UI
                updateGUI(app);
                
            catch ME
                fprintf('GUI Update error: %s\n', ME.message);
            end
        end
        
        function processLine(app, line)
            try
                % Check if it's SLCAN format (starts with 't' or 'T')
                if startsWith(line, 't') || startsWith(line, 'T')
                    processSLCANFrame(app, line);
                    return;
                end
                
                % Otherwise process as CSV format
                parts = strsplit(line, ',');
                if numel(parts) < 2
                    return;
                end
                
                typ = parts{1};
                
                switch typ
                    case 'V'
                        if numel(parts) < 4, return; end
                        seg = str2double(parts{2});
                        cell = str2double(parts{3});
                        val = str2double(parts{4});
                        if seg>=1 && seg<=12 && cell>=1 && cell<=12
                            app.VoltageList(seg, cell) = val;
                        end
                        
                    case 'T'
                        if numel(parts) < 4, return; end
                        seg = str2double(parts{2});
                        cell = str2double(parts{3});
                        val = str2double(parts{4});
                        if seg>=1 && seg<=12 && cell>=1 && cell<=12
                            app.TempsList(seg, cell) = val;
                        end
                        
                    case 'IC'
                        if numel(parts) < 3, return; end
                        ic = str2double(parts{2});
                        val = str2double(parts{3});
                        if ic>=1 && ic<=12
                            app.MAXICTemps(ic) = val;
                        end
                        
                    case 'I'
                        if numel(parts) < 4, return; end
                        app.ts_current = str2double(parts{4});
                        
                    case 'IVT'
                        if numel(parts) < 2, return; end
                        app.ivt_current = str2double(parts{2});
                        
                    case 'C'
                        if numel(parts) < 4, return; end
                        app.charging_current = str2double(parts{4});
                        
                    case 'CC'
                        if numel(parts) < 2, return; end
                        app.charging_current = str2double(parts{2});
                        
                    case 'P'
                        if numel(parts) < 4, return; end
                        app.pack_voltage = str2double(parts{4});
                        
                    case 'S'
                        if numel(parts) < 4, return; end
                        app.charger_connected = strcmp(parts{4}, '1');
                        
                    case 'CH'
                        if numel(parts) < 3, return; end
                        app.charger_connected = logical(str2double(parts{2}));
                        app.charger_status_flag = str2double(parts{3});
                        
                    case 'ERR'
                        if numel(parts) < 2, return; end
                        code = str2double(parts{2});
                        switch code
                            case 0, app.lastError = 'ISOSPI';
                            case 1, app.lastError = 'Voltages';
                            case 2, app.lastError = 'Temps';
                            case 3, app.lastError = 'Current Sensor';
                            otherwise, app.lastError = 'Generic';
                        end
                        if isprop(app, 'ErrorLabel')
                            app.ErrorLabel.Text = "Error: " + app.lastError;
                        end
                end
                
            catch ME
                fprintf('processLine error: %s\n', ME.message);
            end
        end
        function updateGUI(app)
            try
                % FAST VECTORIZED VOLTAGE TABLE
                % Create a base array of "-- V"
                Vdat = repmat("-- V", 12, 12);
                
                % Find all valid voltages (>= 0)
                validV = app.VoltageList >= 0;
                
                % Instantly format all valid numbers into the string array using compose
                if any(validV, 'all')
                    Vdat(validV) = compose("%.3f V", app.VoltageList(validV));
                end
                
                % Push to UI (cellstr ensures perfect uitable compatibility)
                app.UITable3.Data = cellstr(Vdat);
        
                % FAST VECTORIZED TEMPERATURE TABLE
                temp_indices = [2,3,4,5,6,7,8,9,10,12];
                Tdat = repmat("-- °C", 12, 10);
                
                % Extract just the 10 columns we display
                dispTemps = app.TempsList(:, temp_indices);
                validT = dispTemps >= -40; % Using your -40 threshold
                
                if any(validT, 'all')
                    Tdat(validT) = compose("%d °C", round(dispTemps(validT)));
                end
                app.UITable2.Data = cellstr(Tdat);
        
                
                %  VOLTAGE STATISTICS
                
                voltAll = app.VoltageList(app.VoltageList >= 0);
                
                if isprop(app, 'PackVoltLabel')
                    vDec = 3 + double(app.showExtraPrecision);
                    if ~isempty(voltAll)
                        if contains(app.StatusLabel.Text, 'waiting for data')
                            app.StatusLabel.Text = strrep(app.StatusLabel.Text, ' — waiting for data...', '');
                        end
                        
                        % Fast sum of all valid voltages
                        packV = sum(voltAll);
                        app.PackVoltLabel.Text = sprintf('Pack: %.2f V', packV);
                        
                        minV = min(voltAll);
                        maxV = max(voltAll);
                        avgV = mean(voltAll);
                        deltaV = maxV - minV;
                        
                        app.MinVoltLabel.Text = sprintf('Min: %.*f V', vDec, minV);
                        app.MaxVoltLabel.Text = sprintf('Max: %.*f V', vDec, maxV);
                        app.AvgVoltLabel.Text = sprintf('Avg: %.*f V', vDec, avgV);
                        
                        if app.showExtraPrecision
                            app.DeltaVoltLabel.Text = sprintf('Δ: %.*f V', vDec, deltaV);
                        else
                            app.DeltaVoltLabel.Text = ':)';
                        end
                    else
                        if app.showExtraPrecision
                            app.DeltaVoltLabel.Text = 'Δ: -- V';
                        else
                            app.DeltaVoltLabel.Text = ':)';
                        end
                    end
                end
                
                if isprop(app, 'MaxVIDLabel')
                    validV_matrix = app.VoltageList;
                    validV_matrix(validV_matrix < 0) = NaN;
                    if any(~isnan(validV_matrix(:)))
                        [~, idxV] = max(validV_matrix(:), [], 'omitnan');
                        [segV, cellV] = ind2sub(size(validV_matrix), idxV);
                        app.MaxVIDLabel.Text = sprintf('Max V: IC%d/C%d', segV, cellV);
                    else
                        app.MaxVIDLabel.Text = 'Max V: --';
                    end
                end
                
                %  TEMPERATURE STATISTICS
                tempAll = app.TempsList(app.TempsList >= -40);
                
                if isprop(app, 'MinTempLabel')
                    tDec = 1 + double(app.showExtraPrecision);
                    if ~isempty(tempAll)
                        app.MinTempLabel.Text = sprintf('MinT: %.*f °C', tDec, min(tempAll));
                        app.MaxTempLabel.Text = sprintf('MaxT: %.*f °C', tDec, max(tempAll));
                    else
                        app.MinTempLabel.Text = 'MinT: -- °C';
                        app.MaxTempLabel.Text = 'MaxT: -- °C';
                    end
                end                
                
                if isprop(app, 'MaxTIDLabel')
                    Tdisp = app.TempsList(:, temp_indices);
                    Tdisp(Tdisp < -40) = NaN;
                    if any(~isnan(Tdisp(:)))
                        [~, idxT] = max(Tdisp(:), [], 'omitnan');
                        [segT, colLocal] = ind2sub(size(Tdisp), idxT);
                        realTcol = temp_indices(colLocal);
                        app.MaxTIDLabel.Text = sprintf('Max T: IC%d/T%d', segT, realTcol);
                    else
                        app.MaxTIDLabel.Text = 'Max T: --';
                    end
                end
                
                if isprop(app, 'ICMaxTempLabel')
                    maxICT = max(app.MAXICTemps, [], 'omitnan');
                    if ~isnan(maxICT)
                        app.ICMaxTempLabel.Text = sprintf('IC Max: %.1f °C', maxICT);
                    else
                        app.ICMaxTempLabel.Text = 'IC Max: -- °C';
                    end
                end
                
                % TELEMETRY & CHARGER
                if isprop(app, 'TSCurrentLabel')
                    cDec = 2 + double(app.showExtraPrecision);
                    if app.ts_current >= 0
                        current = app.ts_current;
                        app.TSCurrentLabel.Text = sprintf('TS: %.*f A', cDec, current);
                        app.TSCurrentLabel.FontColor = [double(current > 225.0) 0 0];
                    elseif app.ivt_current ~= 0
                        current = app.ivt_current / 1000.0;
                        app.TSCurrentLabel.Text = sprintf('IVT: %.*f A', cDec, current);
                        app.TSCurrentLabel.FontColor = [double(current > 225.0) 0 0];
                    else
                        app.TSCurrentLabel.Text = 'TS: -- A';
                        app.TSCurrentLabel.FontColor = [0 0 0];
                    end
                end                
                
                if isprop(app, 'ChargerStatusLabel')
                    if ~app.isConnected
                        chargerTxt = "Charger: --";
                    else
                        if app.charger_status_flag == -1
                            chargerTxt = "Charger: Unknown";
                        elseif app.charger_status_flag == 0
                            chargerTxt = "Charger: " + string(categorical(app.charger_connected, [true false], {'OK', 'Disconnected'}));
                        elseif app.charger_status_flag == 1
                            chargerTxt = "Charger: No DC Link";
                        else
                            chargerTxt = "Charger: Fault";
                        end
                    end
                    
                    if app.charging_current >= 0
                        cDec = 2 + double(app.showExtraPrecision);
                        chargerTxt = chargerTxt + sprintf(' | %.*fA', cDec, app.charging_current);
                    end
                    app.ChargerStatusLabel.Text = chargerTxt;
                    app.ChargerStatusLabel.FontColor = [0 0.6*double(app.charger_connected) 0];
                end
                
                % SEGMENTS SUMMARY TAB (Vectorized)
                % Fast mean calculation ignoring negatives
                segmentAvgs = NaN(1, 12);
                for s = 1:12
                    validVals = app.VoltageList(s, app.VoltageList(s,:) >= 0);
                    if ~isempty(validVals)
                        segmentAvgs(s) = mean(validVals);
                    end
                end
                
                vDec = 3 + double(app.showExtraPrecision);
                segAvgData = repmat("-- V", 1, 12);
                validSeg = ~isnan(segmentAvgs);
                if any(validSeg)
                    fmtStr = sprintf("%%.%df V", vDec);
                    segAvgData(validSeg) = compose(fmtStr, segmentAvgs(validSeg));
                end
                app.SegmentAvgTable.Data = cellstr(segAvgData);
                
                tDec = 1 + double(app.showExtraPrecision);
                icTempData = repmat("-- °C", 1, 12);
                validIC = ~isnan(app.MAXICTemps);
                if any(validIC)
                    fmtStr = sprintf("%%.%df °C", tDec);
                    icTempData(validIC) = compose(fmtStr, app.MAXICTemps(validIC));
                end
                app.ICTempTable.Data = cellstr(icTempData);
                
                % Log CSV row
                logCSVRow(app);
                
            catch ME
                fprintf('GUI update error: %s\n', ME.message);
            end
        end


        function applyChargerMode(app)

    % Charger mode comes from checkbox
    app.chargerMode = logical(app.BalanceCheckBox.Value);

    % If charger mode OFF -> force stop and disable controls
    if ~app.chargerMode

        if app.chargingState == 1
            stopCharging(app);
        end

        app.ChargeButton.Enable = 'off';
        app.ChargeCurrentInputEditField.Enable = 'off';
        return;
    end

    % Charger mode ON -> enable controls only if charger OK
    chargerOK = app.isConnected && ...
                app.charger_connected && ...
                app.charger_status_flag == 0;
    if chargerOK
            app.ChargeButton.Enable = 'on';
            app.ChargeCurrentInputEditField.Enable = 'on';
        else
            app.ChargeButton.Enable = 'off';
            app.ChargeCurrentInputEditField.Enable = 'off';
    
            if app.chargingState == 1
                stopCharging(app);
            end
    end
   end
        
        function setConnectionUI(app, state)
            switch lower(state)
                case 'connected'
                    app.Image.ImageSource = app.connectedIcon;
                    app.ConnectButton.Text = 'Disconnect';
                case 'connecting'
                    if exist(app.connectingIcon,'file')
                        app.Image.ImageSource = app.connectingIcon;
                    end
                    app.ConnectButton.Text = 'Connecting...';
                otherwise
                    app.Image.ImageSource = app.disconnectedIcon;
                    app.ConnectButton.Text = 'Connect';
            end
        end

        function processSLCANFrame(app, line)
            try
                s = char(line);
                
                % Parse SLCAN format
                if s(1) == 't'
                    % Standard CAN frame: t<III><L><DD...>
                    canId = hex2dec(s(2:4));
                    dlc = hex2dec(s(5));
                    dataHex = s(6:5+2*dlc);
                elseif s(1) == 'T'
                    % Extended CAN frame: T<IIIIIIII><L><DD...>
                    canId = hex2dec(s(2:9));
                    dlc = hex2dec(s(10));
                    dataHex = s(11:10+2*dlc);
                else
                    return;
                end
                
                % Convert hex data to bytes
                dataBytes = uint8(zeros(1, dlc));
                for i = 1:dlc
                    dataBytes(i) = uint8(hex2dec(dataHex(2*i-1:2*i)));
                end
                
                % Decode CAN frame
                decodeCANFrame(app, canId, dataBytes);
                
            catch ME
                fprintf('SLCAN parse error: %s\n', ME.message);
            end
        end

        function decodeCANFrame(app, canId, data)
            try
                switch canId
                    case hex2dec('700')  % Voltages + charger status
                        if numel(data) < 8, return; end
                        
                        b1 = data(1);
                        b2 = data(2);
                        
                        ic = bitand(b1, 15) + 1;  % IC number (1-12)
                        base = bitand(b2, 15);     % Base cell index (0-11)
                        
                        % Extract 3 voltage values
                        for i = 0:2
                            idx = mod(base + i, 12) + 1;  % MATLAB 1-based
                            
                            hi = uint16(data(3 + 2*i));
                            lo = uint16(data(4 + 2*i));
                            rawU16 = bitor(bitshift(hi, 8), lo);
                            rawI16 = typecast(rawU16, "int16");
                            
                            v = (double(rawI16) + 10000) * 0.000150;
                            app.VoltageList(ic, idx) = v;
                        end
                        
                        % Charger status from high nibble of b2
                        hb = bitshift(b2, -4);
                        app.charger_connected = logical(bitand(hb, 1));
                        app.charger_status_flag = bitand(bitshift(hb, -1), 3);
                        if app.chargerMode
                            chargerOK = app.charger_connected && app.charger_status_flag == 0;

                            if ~chargerOK && app.chargingState == 1
                                stopCharging(app);
                            end
                        end
                    case hex2dec('701')  % Temperatures
                        if numel(data) < 3, return; end
                        
                        b1 = data(1);
                        b2 = data(2);
                        
                        ic = bitand(b1, 15) + 1;
                        base = bitand(b2, 15);
                        
                        % Max IC temp
                        maxIcTemp = bitor(bitshift(bitand(bitshift(b1,-4),15),4), bitshift(b2,-4));
                        app.MAXICTemps(ic) = double(maxIcTemp);
                        
                        % Temperature values (map to correct columns)
                        tempMap = [2 3 4 5 6 7 8 9 10 12];
                        nTemps = numel(data) - 2;
                        for i = 0:(nTemps-1)
                            raw_idx = base + i;
                       % Only accept data if the index fits within our 10 expected temperature slots
                        if raw_idx >= 0 && raw_idx < numel(tempMap)
                            col = tempMap(raw_idx + 1); % +1 for MATLAB 1-based indexing
                            t = double(data(3 + i)) - 100;
                            app.TempsList(ic, col) = t;
                        else
                            fprintf('Warning: Ignored invalid temperature index %d from IC %d\n', raw_idx, ic);
                        end
                        
                      
                        end
                        
                    case hex2dec('521')  % IVT Current
                        if numel(data) < 8, return; end
                        raw32 = uint32(data(5))*2^24 + uint32(data(6))*2^16 + ...
                                uint32(data(7))*2^8 + uint32(data(8));
                        app.ivt_current = double(typecast(uint32(raw32), 'int32'));
                        
                    case hex2dec('102')  % Charger Current
                        if numel(data) < 1, return; end
                        app.charging_current = double(data(1)) / 10.0;
                        
                    case hex2dec('101')  % Fault - stop charging
                        if numel(data) < 1, return; end
                        code = double(data(1));
                        switch code
                            case 0, app.lastError = 'ISOSPI';
                            case 1, app.lastError = 'Voltages';
                            case 2, app.lastError = 'Temps';
                            case 3, app.lastError = 'Current Sensor';
                            otherwise, app.lastError = 'Generic';
                        end
                        
                        if isprop(app, 'ErrorLabel')
                            app.ErrorLabel.Text = "Error: " + app.lastError;
                        end
                        
                        % Stop charging if active
                        if app.chargingState == 1
                            stopCharging(app);
                        end
                end
                
            catch ME
                fprintf('CAN decode error: %s\n', ME.message);
            end
        end
    end
    
    
    methods (Access = public)
        
         % Test method to inject data
        function injectTestData(app)
            fprintf('--- Simulating UART/CAN Data Stream ---\n');
            
            % 1. Create a dummy array of strings (Mix of CSV and CAN)
            dummyStream = [
                "P,0,0,52.4"                        % CSV: Pack Voltage
                "I,0,0,15.2"                        % CSV: Current
                "CH,1,0"                            % CSV: Charger Status
                "t700800003A983A983A98"             % CAN 700: IC1, Cells 1-3 (~3.75V)
                "t7015010891969B"                   % CAN 701: IC2, Temp Base 8 (Intentionally pushes out-of-bounds!)
            ];
            
            % 2. Trick the app into thinking it is connected so safety limits work
            app.isConnected = true; 
            
            % 3. Feed the strings into the parser exactly as the serial port would
            for i = 1:length(dummyStream)
                line = char(dummyStream(i));
                fprintf('Rx: %s\n', line);
                
                % Inject straight into the parser
                processLine(app, line);
            end
            
            % 4. Force a GUI update to see the results instantly
            updateGUI(app);
            
            fprintf('--- UART Simulation Complete ---\n');
        end
        function startCharging(app)
                   % Charger mode must be enabled
            
            
            % Charger must be connected and OK
            if ~app.charger_connected || app.charger_status_flag ~= 0
                app.ErrorLabel.Text = 'Error: Charger Not Ready';
                app.ErrorLabel.FontColor = [1 0 0];
                app.ChargeButton.Value = 0;
                return;
            end
            app.chargerMode = true;
            if ~app.isConnected || isempty(app.sp)
                if isprop(app, 'ErrorLabel')
                    app.ErrorLabel.Text = 'Error: Not connected';
                    app.ErrorLabel.FontColor = [1 0 0];
                end
                if isprop(app, 'ChargeButton')
                    app.ChargeButton.Value = 0;
                end
                return;
            end
            
            % Get current value from input
            if isprop(app, 'ChargeCurrentInputEditField')
                curr = app.ChargeCurrentInputEditField.Value;
            else
                curr = 0;
            end
            
            % STRICT VALIDATION - must be between 0 and 6A
            if isnan(curr) || curr < 0 || curr > 6
                if isprop(app, 'ErrorLabel')
                    app.ErrorLabel.Text = 'Error: Current must be 0-6 A';
                    app.ErrorLabel.FontColor = [1 0 0];
                end
                if isprop(app, 'ChargeButton')
                    app.ChargeButton.Value = 0;
                end
                
                % Reset to valid value
                if isprop(app, 'ChargeCurrentInputEditField')
                    app.ChargeCurrentInputEditField.Value = min(max(curr, 0), 6);
                end
                
                uialert(app.UIFigure, ...
                    'Charge current must be between 0 and 6 Amps.', ...
                    'Invalid Current', 'Icon', 'warning');
                return;
            end
            
            % Calculate hex code for current (same as Python)
            rawVal = int32(curr * 10) + 100;
            app.chargeHexCode = upper(dec2hex(rawVal, 2));
            
            % Get balance flag
            balanceFlag = 0;
            if isprop(app, 'BalanceCheckBox')
                balanceFlag = app.BalanceCheckBox.Value;
            end
            
            % Send charge ON command
            on_off = '0';  % 0 = ON
            cmd = sprintf('T1502%s%d%s', app.chargeHexCode, balanceFlag, on_off);
            
            try
                writeline(app.sp, cmd);
                writeline(app.sp, 'L1');  % SLCAN command confirmation
                
                app.chargingState = 1;
                
                if isprop(app, 'StatusLabel')
                    if curr == 0
                        app.StatusLabel.Text = 'Charging 0 A (float mode)';
                    else
                        app.StatusLabel.Text = sprintf('Charging %.1f A', curr);
                    end
                    app.StatusLabel.FontColor = [0 0.6 0];  % Green
                end
                
                if isprop(app, 'ErrorLabel')
                    app.ErrorLabel.Text = 'Error: None';
                    app.ErrorLabel.FontColor = [0 0 0];
                end
                
                fprintf('Charging started: %.1f A (hex: %s)\n', curr, app.chargeHexCode);
                
            catch ME
                if isprop(app, 'ErrorLabel')
                    app.ErrorLabel.Text = 'Error: ' + string(ME.message);
                    app.ErrorLabel.FontColor = [1 0 0];
                end
                if isprop(app, 'ChargeButton')
                    app.ChargeButton.Value = 0;
                end
                app.chargingState = 0;
                fprintf('Charging start failed: %s\n', ME.message);
            end
        end
        
            function stopCharging(app)
            if isempty(app.sp)
                app.chargingState = 0;
                if isprop(app, 'ChargeButton')
                    app.ChargeButton.Value = 0;
                end
                return;
            end
            
            % Get balance flag
            balanceFlag = 0;
            if isprop(app, 'BalanceCheckBox')
                balanceFlag = app.BalanceCheckBox.Value;
            end
            
            % Send charge OFF command
            on_off = '1';  % 1 = OFF
            cmd = sprintf('T1502%s%d%s', app.chargeHexCode, balanceFlag, on_off);
            
            try
                app.chargingState = 0;
                writeline(app.sp, cmd);
                writeline(app.sp, 'L1');
                
                if isprop(app, 'StatusLabel')
                    app.StatusLabel.Text = 'Charging OFF';
                    app.StatusLabel.FontColor = [1 0.6 0];  % Orange
                end
                
                if isprop(app, 'ChargeButton')
                    app.ChargeButton.Value = 0;
                end
                
                fprintf('Charging stopped\n');
                
            catch ME
                if isprop(app, 'ErrorLabel')
                    app.ErrorLabel.Text = 'Error: ' + string(ME.message);
                end
            end
        end

        function safetyCheck(app)
            if ~app.isConnected
                return;
            end
            
            voltAll = app.VoltageList(app.VoltageList >= 0);
            tempAll = app.TempsList(app.TempsList >= -40);
            
            overV = ~isempty(voltAll) && max(voltAll) > app.MAX_VOLTAGE_THRESHOLD;
            underV = ~isempty(voltAll) && min(voltAll) < app.MIN_VOLTAGE_THRESHOLD;
            overT = ~isempty(tempAll) && max(tempAll) > app.TEMP_WARN_LIMIT;
            
            % Only stop if currently charging
            if app.chargingState == 1 && (overV || underV || overT)
                stopCharging(app);
                
                if overV
                    reason = "Over-voltage";
                elseif underV
                    reason = "Under-voltage";
                else
                    reason = "Over-temperature";
                end
                
                if isprop(app, 'ErrorLabel')
                    app.ErrorLabel.Text = "SAFETY: " + reason;
                    app.ErrorLabel.FontColor = [1 0 0];  % Red
                end
                
                if isprop(app, 'StatusLabel')
                    app.StatusLabel.Text = "STOPPED: " + reason;
                    app.StatusLabel.FontColor = [1 0 0];  % Red
                end
                
                fprintf('SAFETY STOP: %s\n', reason);
            end
        end

        function sendHeartbeat(app)
            if ~app.isConnected || isempty(app.sp)
                return;
            end
            
            try
                % Get balance flag
                balanceFlag = 0;
                if isprop(app, 'BalanceCheckBox')
                    balanceFlag = app.BalanceCheckBox.Value;
                end
                
                % Determine on/off based on charging state
                on_off = '1';  % Default OFF
                if app.chargingState == 1
                    on_off = '0';  % ON
                end
                
                % Send heartbeat command
                hb = sprintf('T1502%s%d%s', app.chargeHexCode, balanceFlag, on_off);
                writeline(app.sp, hb);
                writeline(app.sp, 'L1');

                
            catch
                % Ignore heartbeat errors
            end
        end

        function forceDisconnect(app, reason)
            % Updated to gracefully close whichever connection is active (Serial or WiFi)
            fprintf('Force disconnect: %s\n', reason);
            
            app.isConnected = false;
            app.isClosing = true;
            
            % Stop all timers
            try
                timersToStop = [app.MainTimer, app.heartbeatTimer];
                for t = timersToStop
                    if ~isempty(t) && isvalid(t)
                        stop(t);
                    end
                end
            catch
            end
                % Close serial port
        try
            if ~isempty(app.sp) && isvalid(app.sp)
                configureCallback(app.sp, "off");
                delete(app.sp);
            end
        catch
        end
        app.sp = [];    


            % Update UI
            setConnectionUI(app, 'disconnected');
            
            if isprop(app, 'StatusLabel')
                app.StatusLabel.Text = sprintf('Disconnected (%s)', reason);
                app.StatusLabel.FontColor = [1 0.2 0.2];
            end
            
            if isprop(app, 'ChargeButton')
                app.ChargeButton.Value = 0;
            end
            if isprop(app, 'ConnectButton')
                app.ConnectButton.Text = 'Connect';
            end
            
            app.chargingState = 0;
            app.isClosing = false;
        end

        function openCSVLog(app)
            if app.logFID >= 0
                return; % Already open
            end
            
            path = fullfile(pwd, app.logFile);
            newFile = ~isfile(path);
            
            [fid, msg] = fopen(path, "a");
            if fid < 0
                if isprop(app, 'ErrorLabel')
                    app.ErrorLabel.Text = "CSV Error: " + string(msg);
                end
                app.LogtoCSVCheckBox.Value = 0;
                app.logEnabled = false;
                return;
            end
            
            app.logFID = fid;
            
            % Write header if new file
            if newFile
                writeCSVHeader(app);
                app.csvHeaderWritten = true;
            end
        end
        
        function writeCSVHeader(app)
            % Write comprehensive header
            fprintf(app.logFID, 'timestamp,packV,minV,maxV,avgV,deltaV,minT,maxT,ivtA,chargerA,chargerConn,chargerStatus,lastError');
            
            % Voltage columns (12 ICs × 12 cells = 144 columns)
            for ic = 1:12
                for c = 1:12
                    fprintf(app.logFID, ',V_%d_%d', ic, c);
                end
            end
            
            % Temperature columns (12 ICs × 10 displayed temps = 120 columns)
            temp_indices = [2,3,4,5,6,7,8,9,10,12];
            for ic = 1:12
                for t = temp_indices
                    fprintf(app.logFID, ',T_%d_%d', ic, t);
                end
            end
            
            % IC temperature columns (12 ICs)
            for ic = 1:12
                fprintf(app.logFID, ',ICtemp_%d', ic);
            end
            
            fprintf(app.logFID, '\n');
        end
        
        function closeCSVLog(app)
            try
                if app.logFID >= 0
                    fclose(app.logFID);
                end
            catch
            end
            app.logFID = -1;
        end
        
        function logCSVRow(app)
            if ~app.logEnabled || app.logFID < 0
                return;
            end
            
            try
                % Calculate statistics
                voltAll = app.VoltageList(app.VoltageList >= 0);
                tempAll = app.TempsList(app.TempsList >= -40);
                
                packV = NaN; minV = NaN; maxV = NaN; avgV = NaN; deltaV = NaN;
                minT = NaN; maxT = NaN;
                
                
                    if ~isempty(voltAll)
                        packV = 0;
                        for s = 1:12
                        segVals = app.VoltageList(s, app.VoltageList(s,:) >= 0);
                        if ~isempty(segVals)
                            packV = packV + sum(segVals);
                        end
                 end
                    
                    minV = min(voltAll);
                    maxV = max(voltAll);
                    avgV = mean(voltAll);
                    deltaV = maxV - minV;
                end
                
                if ~isempty(tempAll)
                    minT = min(tempAll);
                    maxT = max(tempAll);
                end
                
                ivtA = app.ivt_current / 1000.0;  % Convert mA to A
                chgA = app.charging_current;
                t = toc(app.t0);
                
                % Write summary columns
                fprintf(app.logFID, ...
                    '%.3f,%.3f,%.3f,%.3f,%.3f,%.3f,%.1f,%.1f,%.3f,%.3f,%d,%d,%s', ...
                    t, packV, minV, maxV, avgV, deltaV, ...
                    minT, maxT, ivtA, chgA, ...
                    app.charger_connected, app.charger_status_flag, ...
                    string(app.lastError));
                
                % Write all voltage values (12×12 = 144 values)
                for ic = 1:12
                    for c = 1:12
                        v = app.VoltageList(ic, c);
                        if v >= 0
                            fprintf(app.logFID, ',%.4f', v);
                        else
                            fprintf(app.logFID, ',NaN');
                        end
                    end
                end
                
                % Write temperature values (12×10 = 120 values)
               temp_indices = [2,3,4,5,6,7,8,9,10,12];
                for ic = 1:12
                    for tidx = temp_indices
                        temp = app.TempsList(ic, tidx);
                        if temp >= -40
                            fprintf(app.logFID, ',%.2f', temp);
                        else
                            fprintf(app.logFID, ',NaN');
                        end
                    end
                end
                
                % Write IC temperatures (12 values)
                for ic = 1:12
                    ict = app.MAXICTemps(ic);
                    if ~isnan(ict)
                        fprintf(app.logFID, ',%.1f', ict);
                    else
                        fprintf(app.logFID, ',NaN');
                    end
                end
                
                fprintf(app.logFID, '\n');
                
            catch ME
                fprintf('CSV logging error: %s\n', ME.message);
            end
        end


    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % CLEANUP FIRST
           try
                existingTimers = timerfindall('Tag', 'BMS_Node_Timer');
                if ~isempty(existingTimers)
                    stop(existingTimers);
                    delete(existingTimers);
                    fprintf('Cleaned up %d orphaned BMS timers\n', length(existingTimers));
                end
            catch
            end
            
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.connectedIcon    = fullfile(pathToMLAPP,'connected.png');
            app.disconnectedIcon = fullfile(pathToMLAPP,'disconnected.png');
            app.connectingIcon   = fullfile(pathToMLAPP,'connecting.png');
        
            initTables(app);
            
            % Initialize DeltaVoltLabel with smiley
            if isprop(app, 'DeltaVoltLabel')
                app.DeltaVoltLabel.Text = ':)';
            end
            
            % Initialize timestamp base
            app.t0 = tic;
            app.lastRxTime = tic;
            
            fprintf('App started.\n');
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            

            if app.isConnected
                % Disconnect whichever is active
                disconnectPort(app);
                
                return;
            end
        
           connectPort(app);

        end

        % Value changed function: LogtoCSVCheckBox
        function LogtoCSVCheckBoxValueChanged(app, event)
            app.logEnabled = app.LogtoCSVCheckBox.Value;

            if app.logEnabled
                openCSVLog(app);
                if app.logFID < 0
                    % Failed to open
                    app.LogtoCSVCheckBox.Value = false;
                    app.logEnabled = false;
                else
                    if isprop(app, 'StatusLabel')
                        app.StatusLabel.Text = app.StatusLabel.Text + " [Logging]";
                    end
                end
            else
                closeCSVLog(app);
                if isprop(app, 'StatusLabel')
                    app.StatusLabel.Text = strrep(app.StatusLabel.Text, ' [Logging]', '');
                end
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            fprintf('Closing app...\n');
    
            % Set closing flag FIRST to stop all callbacks
            app.isClosing = true;
            app.isConnected = false;
            
            % Wait a moment for any running callbacks to exit
            pause(0.3);
            
            % Stop and delete timers
            try
                if ~isempty(app.MainTimer) && isvalid(app.MainTimer)
                    stop(app.MainTimer);
                    delete(app.MainTimer);
                end
                fprintf('Timer stopped.\n');
            catch ME
                fprintf('Timer error: %s\n', ME.message);
            end
            
            % Also delete ALL timers as backup
            try
                allTimers = timerfindall;
                if ~isempty(allTimers)
                    stop(allTimers);
                    delete(allTimers);
                end
            catch
            end


            % Close serial
            try
                if ~isempty(app.sp) && isvalid(app.sp)
                    delete(app.sp);
                    fprintf('Serial closed.\n');
                end
            catch ME
                fprintf('Serial error: %s\n', ME.message);
            end
            app.sp = [];
            
            % Close log
            try
                if app.logFID > 0
                    fclose(app.logFID);
                end
            catch
            end
            
            fprintf('Deleting figure...\n');
            delete(app.UIFigure);
            fprintf('App closed.\n');
        end

        % Size changed function: UIFigure
        function UIFigureSizeChanged(app, event)
            %forceLayout(app);
            
        end

        % Value changed function: ChargeButton
        function ChargeButtonValueChanged(app, event)
            if app.ChargeButton.Value == 1
                startCharging(app);
            else
                stopCharging(app);
            end
            
        end

        % Value changed function: PrecisionCheckBox
        function PrecisionCheckBoxValueChanged(app, event)
            app.showExtraPrecision = app.PrecisionCheckBox.Value;
    
            % Update DeltaVoltLabel immediately
            if isprop(app, 'DeltaVoltLabel')
              if app.showExtraPrecision
                app.DeltaVoltLabel.Visible = 'on';
                app.DeltaVoltLabel.Text = 'Δ: -- V';
            else
                app.DeltaVoltLabel.Text = ':)';
             end
           end
            
            % Refresh GUI
            updateGUI(app);
            
        end

        % Value changed function: ChargeCurrentInputEditField
        function ChargeCurrentInputEditFieldValueChanged(app, event)
            value = app.ChargeCurrentInputEditField.Value;
    
            % Clamp to valid range
            if value < 0
                app.ChargeCurrentInputEditField.Value = 0;
            elseif value > 6
                app.ChargeCurrentInputEditField.Value = 6;
                uialert(app.UIFigure, ...
                    'Maximum charge current is 6 Amps.', ...
                    'Maximum Current Reached', 'Icon', 'warning');
            end
            
        end

        % Button pushed function: TESTButton
        function TESTButtonPushed2(app, event)
            injectTestData(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @UIFigureSizeChanged, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.LiveTempsandVoltagesTab = uitab(app.TabGroup);
            app.LiveTempsandVoltagesTab.Title = 'Live Temps and Voltages';
            app.GridLayout = uigridlayout(app.LiveTempsandVoltagesTab);
            app.TemepraturesPanel = uipanel(app.GridLayout);
            app.TemepraturesPanel.Title = 'Temepratures';
            app.UITable2 = uitable(app.TemepraturesPanel);
            app.UITable2.ColumnName = {'T2'; 'T3'; 'T4'; 'T5'; 'T6'; 'T7'; 'T8'; 'T9'; 'T10'; 'T12'};
            app.UITable2.RowName = {'1,2,3,4,5,6,7,8,9,10,11,12'};
            app.UITable2.Tag = 'TempsTable';
            app.ICTempTable = uitable(app.TemepraturesPanel);
            app.ICTempTable.ColumnName = {'IC1'; 'IC2'; 'IC3'; 'IC4'; 'IC5'; 'IC6'; 'IC7'; 'IC8'; 'IC9'; 'IC10'; 'IC11'; 'IC12'};
            app.ICTempTable.RowName = {};
            app.VoltagesPanel = uipanel(app.GridLayout);
            app.VoltagesPanel.Title = 'Voltages';
            app.UITable3 = uitable(app.VoltagesPanel);
            app.UITable3.ColumnName = {'V1'; 'V2'; 'V3'; 'V4'; 'V5'; 'V6'; 'V7'; 'V8'; 'V9'; 'V10'; 'V11'; 'V12'};
            app.UITable3.RowName = {};
            app.UITable3.Tag = 'VoltsTable';
            app.SegmentAvgTable = uitable(app.VoltagesPanel);
            app.SegmentAvgTable.ColumnName = {'Seg1'; 'Seg2'; 'Seg3'; 'Seg4'; 'Seg5'; 'Seg6'; 'Seg7'; 'Seg8'; 'Seg9'; 'Seg10'; 'Seg11'; 'Seg12'};
            app.SegmentAvgTable.RowName = {};
            app.ControlsPanel = uipanel(app.GridLayout);
            app.ControlsPanel.Title = 'Controls';
            app.ControlsPanel.Tag = 'Controls';
            app.ConnectButton = uibutton(app.ControlsPanel, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Tag = 'ConnectStateButton';
            app.ConnectButton.Text = 'Connect';
            app.LogtoCSVCheckBox = uicheckbox(app.ControlsPanel);
            app.LogtoCSVCheckBox.ValueChangedFcn = createCallbackFcn(app, @LogtoCSVCheckBoxValueChanged, true);
            app.LogtoCSVCheckBox.Text = 'Log to CSV';
            app.Image = uiimage(app.ControlsPanel);
            app.ChargerStatusLabel = uilabel(app.ControlsPanel);
            app.ChargerStatusLabel.Text = 'ChargerStatusLabel';
            app.ChargeButton = uibutton(app.ControlsPanel, 'state');
            app.ChargeButton.ValueChangedFcn = createCallbackFcn(app, @ChargeButtonValueChanged, true);
            app.ChargeButton.Text = 'Charge';
            app.BalanceCheckBox = uicheckbox(app.ControlsPanel);
            app.BalanceCheckBox.Text = 'Balance';
            app.StatusLabel = uilabel(app.ControlsPanel);
            app.StatusLabel.Text = 'StatusLabel';
            app.PackVoltLabel = uilabel(app.ControlsPanel);
            app.PackVoltLabel.Text = 'PackVoltLabel';
            app.MinVoltLabel = uilabel(app.ControlsPanel);
            app.MinVoltLabel.Text = 'MinVoltLabel';
            app.MaxVoltLabel = uilabel(app.ControlsPanel);
            app.MaxVoltLabel.Text = 'MaxVoltLabel';
            app.AvgVoltLabel = uilabel(app.ControlsPanel);
            app.AvgVoltLabel.Text = 'AvgVoltLabel';
            app.DeltaVoltLabel = uilabel(app.ControlsPanel);
            app.DeltaVoltLabel.Text = 'DeltaVoltLabel';
            app.MinTempLabel = uilabel(app.ControlsPanel);
            app.MinTempLabel.Text = 'MinTempLabel';
            app.MaxTempLabel = uilabel(app.ControlsPanel);
            app.MaxTempLabel.Text = 'MaxTempLabel';
            app.TSCurrentLabel = uilabel(app.ControlsPanel);
            app.TSCurrentLabel.Text = 'TSCurrentLabel';
            app.MaxVIDLabel = uilabel(app.ControlsPanel);
            app.MaxVIDLabel.Text = 'MaxVIDLabel';
            app.ErrorLabel = uilabel(app.ControlsPanel);
            app.ErrorLabel.Text = 'ErrorLabel';
            app.MaxTIDLabel = uilabel(app.ControlsPanel);
            app.MaxTIDLabel.Text = 'MaxTIDLabel';
            app.ICMaxTempLabel = uilabel(app.ControlsPanel);
            app.ICMaxTempLabel.Text = 'ICMaxTempLabel';
            app.PrecisionCheckBox = uicheckbox(app.ControlsPanel);
            app.PrecisionCheckBox.ValueChangedFcn = createCallbackFcn(app, @PrecisionCheckBoxValueChanged, true);
            app.PrecisionCheckBox.Text = 'Precision';
            app.TESTButton = uibutton(app.ControlsPanel, 'push');
            app.TESTButton.ButtonPushedFcn = createCallbackFcn(app, @TESTButtonPushed2, true);
            app.TESTButton.Text = 'TEST';
            app.PortEditFieldLabel = uilabel(app.ControlsPanel);
            app.PortEditFieldLabel.Text = 'Port';
            app.PortEditField = uieditfield(app.ControlsPanel, 'text');
            app.PortEditField.Tag = 'PortEditField';
            app.PortEditField.Value = 'COM3';
            app.BaudRateDropDownLabel = uilabel(app.ControlsPanel);
            app.BaudRateDropDownLabel.Text = 'BaudRate';
            app.BaudRateDropDown = uidropdown(app.ControlsPanel);
            app.BaudRateDropDown.Items = {'125kbit/s', '250kbit/s', '500kbit/s', '1000kbit/s'};
            app.BaudRateDropDown.Value = '500kbit/s';
            app.ChargeCurrentInputEditFieldLabel = uilabel(app.ControlsPanel);
            app.ChargeCurrentInputEditFieldLabel.Text = 'ChargeCurrentInput';
            app.ChargeCurrentInputEditField = uieditfield(app.ControlsPanel, 'numeric');
            app.ChargeCurrentInputEditField.ValueChangedFcn = createCallbackFcn(app, @ChargeCurrentInputEditFieldValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BMS_FV

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end