classdef Revamp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        LiveTempsandVoltagesTab      matlab.ui.container.Tab
        GridLayout                   matlab.ui.container.GridLayout
        ControlsPanel                matlab.ui.container.Panel
        PrecisionCheckBox            matlab.ui.control.CheckBox
        ICMaxTempLabel               matlab.ui.control.Label
        MaxTIDLabel                  matlab.ui.control.Label
        ErrorLabel                   matlab.ui.control.Label
        MaxVIDLabel                  matlab.ui.control.Label
        TSCurrentLabel               matlab.ui.control.Label
        MaxTempLabel                 matlab.ui.control.Label
        MinTempLabel                 matlab.ui.control.Label
        DeltaVoltLabel               matlab.ui.control.Label
        AvgVoltLabel                 matlab.ui.control.Label
        MaxVoltLabel                 matlab.ui.control.Label
        MinVoltLabel                 matlab.ui.control.Label
        PackVoltLabel                matlab.ui.control.Label
        StatusLabel                  matlab.ui.control.Label
        BalanceCheckBox              matlab.ui.control.CheckBox
        ChargeButton                 matlab.ui.control.StateButton
        ChargerStatusLabel           matlab.ui.control.Label
        ChargeCurrentInputEditField  matlab.ui.control.NumericEditField
        ChargeCurrentInputEditFieldLabel  matlab.ui.control.Label
        BaudRateDropDown             matlab.ui.control.DropDown
        BaudRateDropDownLabel        matlab.ui.control.Label
        Image                        matlab.ui.control.Image
        LogtoCSVCheckBox             matlab.ui.control.CheckBox
        ConnectButton                matlab.ui.control.Button
        PortEditField                matlab.ui.control.EditField
        PortEditFieldLabel           matlab.ui.control.Label
        VoltagesPanel                matlab.ui.container.Panel
        UITable3                     matlab.ui.control.Table
        TemepraturesPanel            matlab.ui.container.Panel
        UITable2                     matlab.ui.control.Table
        SecondaryTab                 matlab.ui.container.Tab
        SummaryPanel                 matlab.ui.container.Panel
        SegmentAvgTable              matlab.ui.control.Table
        ICTempTable                  matlab.ui.control.Table
    end

    
    properties (Access = private)
        % Data Storage (12x12 for voltages, 12x12 for temps but only using specific columns)
        VoltageList double = -1 * ones(12,12)
        TempsList double   = -1 * ones(12,12)  % Changed from 12x10 to 12x12
        MAXICTemps double = NaN(1,12)  % IC temperature readings
        
        % Telemetry
        ts_current double = -1
        charging_current double = -1
        pack_voltage double = -1
        charger_connected logical = false
        charger_status_flag double = -1
        ivt_current double = 0  % mA from IVT sensor
        mainLoopCounter double = 0
        wifiLoopCounter double = 0
        % Serial + updates
        sp = []
        isConnected logical = false
    
        % Charging Control
        chargingState double = 0  % 0=OFF, 1=ON
        chargeHexCode char = '64'  % Default current code for CAN
        
        % Safety Thresholds
        MAX_VOLTAGE_THRESHOLD double = 4.15  % V
        MIN_VOLTAGE_THRESHOLD double = 2.7   % V
        TEMP_WARN_LIMIT double = 59          % °C
    
        % Logging
        logEnabled logical = false
        logFID double = -1
        logFile string = "bms_log.csv"
        csvHeaderWritten logical = false
        t0 double = 0  % Timestamp base
    
        % UI refresh
        MainTimer timer
        heartbeatTimer timer  % For sending heartbeat to BMS
        isClosing logical = false
        lastRxTime double = 0
        CONNECTION_TIMEOUT double = 5.0
        
        % Display Settings
        showExtraPrecision logical = false
        
        % Error Tracking
        lastError char = 'None'
        
        % Connections
        connectedIcon   string = 'connected.png';
        disconnectedIcon string = 'disconnected.png';
        connectingIcon  string = 'connecting.jpg';

        udpRx                       % udpport object
        wifiTimer                   
        wifiEnabled logical = false
    
        wifiLocalPort double = 5005
        wifiRemoteIP   char  = "192.168.4.1"   % αν έχεις ESP32 AP, αλλιώς βάλε IP του sender
        wifiRemotePort double = 5006           % port που ακούει ο telemetry node (μόνο αν θες send)
        
        chargerMode logical = false
    end
    
    properties (Access = public)
        Property16 % Description
    end


    methods (Access = private)
        
       function initTables(app)
    % Voltages table (UITable3): 12x12
    app.UITable3.ColumnName = arrayfun(@(k)sprintf('V%d',k), 1:12, 'UniformOutput', false);
    app.UITable3.RowName    = arrayfun(@(k)sprintf('S%d',k), 1:12, 'UniformOutput', false);
    app.UITable3.Data       = repmat({ '-- V' }, 12, 12);

    % Temps table (UITable2): Display only T2,T3,T4,T5,T6,T7,T8,T9,T10,T12
    app.UITable2.ColumnName = {'T2', 'T3', 'T4', 'T5', 'T6', 'T7', 'T8', 'T9', 'T10', 'T12'};
    app.UITable2.RowName    = arrayfun(@(k)sprintf('S%d',k), 1:12, 'UniformOutput', false);
    app.UITable2.Data       = repmat({ '-- °C' }, 12, 10);

    % ===== SEGMENTS TAB TABLES =====
    % ===== SEGMENTS TAB TABLES =====
    % Segment Average Voltages Table: 1 row x 12 columns (TOP TABLE)
    app.SegmentAvgTable.ColumnName = arrayfun(@(k)sprintf('Seg%d',k), 1:12, 'UniformOutput', false);  % CHANGED IC to Seg
    app.SegmentAvgTable.RowName = {'Avg Voltage'};
    app.SegmentAvgTable.Data = zeros(1, 12);
    app.SegmentAvgTable.ColumnWidth = '1x';
    
    % IC Temperature Table: 1 row x 12 columns (BOTTOM TABLE)
    app.ICTempTable.ColumnName = arrayfun(@(k)sprintf('IC%d',k), 1:12, 'UniformOutput', false);
    app.ICTempTable.RowName = {'IC Temp'};
    app.ICTempTable.Data = zeros(1, 12);
    app.ICTempTable.ColumnWidth = '1x';
end
    
        function connectPort(app)
            port = strtrim(app.PortEditField.Value);
            if isempty(port)
                uialert(app.UIFigure,'Enter a port.','No Port');
                return;
            end
        
            try
                fprintf('Attempting to connect to %s...\n', port);
                
                % FORCE cleanup of any existing connection first
                app.isConnected = false;
                app.isClosing = true;
                
                % Clean up any existing timers
                try
                    if ~isempty(app.MainTimer) && isvalid(app.MainTimer)
                        stop(app.MainTimer);
                        delete(app.MainTimer);
                    end
                catch
                end
                
                try
                    if ~isempty(app.heartbeatTimer) && isvalid(app.heartbeatTimer)
                        stop(app.heartbeatTimer);
                        delete(app.heartbeatTimer);
                    end
                catch
                end
                
                % Clean up ALL timers as backup
                try
                    allTimers = timerfindall;
                    if ~isempty(allTimers)
                        stop(allTimers);
                        delete(allTimers);
                        fprintf('Cleaned up %d existing timers before connecting.\n', length(allTimers));
                    end
                catch
                end
                
                pause(0.2);  % Let everything settle
                
                % Clean up any existing serial port
                if ~isempty(app.sp)
                    try
                        delete(app.sp);
                    catch
                    end
                    app.sp = [];
                end
                
                app.isClosing = false;  % Reset the flag
                
                % Open serial port
                fprintf('Opening serial port...\n');
                baud_uart = 9600;  % UART baud for CANdapter
                app.sp = serialport(port, baud_uart, 'Timeout', 0.5);
                configureTerminator(app.sp, "CR");  % CANdapter uses CR
                flush(app.sp);
                
                % Initialize CANdapter
                try
                    writeline(app.sp, "C");  % Close any previous connection
                    writeline(app.sp, "L1");
                catch
                    % Ignore errors
                end
                pause(0.1);
                
                % Set CAN bitrate based on dropdown
                if isprop(app, 'BaudRateDropDown')
                    switch app.BaudRateDropDown.Value
                        case '125kbit/s'
                            can_cmd = 'S4';
                        case '250kbit/s'
                            can_cmd = 'S5';
                        case '500kbit/s'
                            can_cmd = 'S6';
                        case '1000kbit/s'
                            can_cmd = 'S8';
                        otherwise
                            can_cmd = 'S6';  % Default 500kbit/s
                    end
                else
                    can_cmd = 'S6';  % Default if no dropdown
                end
                
                try
                    writeline(app.sp, can_cmd);
                    writeline(app.sp, "L1");
                catch
                    % Ignore errors
                end
                pause(0.1);
                
                % Open CAN channel
                try
                    writeline(app.sp, "O");
                    writeline(app.sp, "L1");
                catch
                    % Ignore errors
                end
                pause(0.1);
                
                % Send initial frame with balance flag
                balanceFlag = 0;
                if isprop(app, 'BalanceCheckBox')
                    balanceFlag = app.BalanceCheckBox.Value;
                end
                
                init = sprintf('T1502%s%d1', app.chargeHexCode, balanceFlag);
                try
                    writeline(app.sp, init);
                    writeline(app.sp, "L1");
                catch
                    % Ignore errors
                end
                
                % Wait for response to verify connection (optional - less strict than before)
                fprintf('Verifying connection...\n');
                verified = false;
                startTime = tic;
                
                while toc(startTime) < 2.0  % Wait up to 2 seconds for response
                    if app.sp.NumBytesAvailable > 0
                        try
                            line = strtrim(readline(app.sp));
                            if ~isempty(line)
                                fprintf('Received: %s\n', line);
                                % Accept any response as verification
                                % (SLCAN format starts with 't' or 'T', CSV has commas)
                                if startsWith(line, 't') || startsWith(line, 'T') || contains(line, ',')
                                    verified = true;
                                    fprintf('Connection verified with data: %s\n', line);
                                    break;
                                end
                            end
                        catch
                        end
                    end
                    pause(0.05);
                end
                
                % Don't fail if not verified - device might take longer to respond
                if ~verified
                    fprintf('No response yet, but continuing...\n');
                end
                
                % Update state
                app.isConnected = true;
                app.lastRxTime = tic;  % Initialize last receive time
                
                % Create Main timer for reading serial data
                fprintf('Creating main timer...\n');
                app.MainTimer = timer( ...
                    'ExecutionMode', 'fixedRate', ...
                    'Period', 0.5, ...
                    'TimerFcn', @(~,~)mainLoop(app));
                start(app.MainTimer);
                fprintf('Main timer started.\n');
                
                % Create Heartbeat timer (200ms like Python)
                fprintf('Creating heartbeat timer...\n');
                app.heartbeatTimer = timer( ...
                    'ExecutionMode', 'fixedRate', ...
                    'Period', 0.2, ...
                    'TimerFcn', @(~,~)sendHeartbeat(app));
                start(app.heartbeatTimer);
                fprintf('Heartbeat timer started.\n');
                
                % Update UI
                setConnectionUI(app, 'connected');
                
                if isprop(app, 'StatusLabel')
                    app.StatusLabel.Text = sprintf('Connected to %s — waiting for data...', port);
                    app.StatusLabel.FontColor = [0 0.8 0]; % Green
                end
                
                if isprop(app, 'ErrorLabel')
                    app.ErrorLabel.Text = 'Error: None';
                end
                
                fprintf('Connected successfully!\n');
                
            catch ME
                fprintf('Connection error: %s\n', ME.message);
                app.isConnected = false;
                app.isClosing = false;
                
                % Cleanup on failure
                try
                    allTimers = timerfindall;
                    if ~isempty(allTimers)
                        stop(allTimers);
                        delete(allTimers);
                    end
                catch
                end
                
                if ~isempty(app.sp)
                    try
                        delete(app.sp);
                    catch
                    end
                    app.sp = [];
                end
                
                setConnectionUI(app, 'disconnected');
                
                if isprop(app, 'StatusLabel')
                    app.StatusLabel.Text = 'Connection Failed';
                    app.StatusLabel.FontColor = [1 0.2 0.2]; % Red
                end
                
                if isprop(app, 'ErrorLabel')
                    app.ErrorLabel.Text = 'Error: ' + string(ME.message);
                end
                
                uialert(app.UIFigure, sprintf('Connection Failed: %s', ME.message), 'Connection Error');
            end
        end
        
        function disconnectPort(app)
            fprintf('Disconnecting...\n');
            app.isConnected = false;
            app.isClosing = true;  % Stop the mainLoop from running
        
            % Wait for any running callbacks to finish
            pause(0.2);
        
            % Stop and delete timer - try multiple ways
            try
                if ~isempty(app.MainTimer) && isvalid(app.MainTimer)
                    stop(app.MainTimer);
                    delete(app.MainTimer);
                    fprintf('Timer stopped and deleted.\n');
                end
            catch ME
                fprintf('Timer stop error: %s\n', ME.message);
            end
            
            % Also try to delete by finding all timers (in case reference is lost)
            try
                allTimers = timerfindall;
                if ~isempty(allTimers)
                    stop(allTimers);
                    delete(allTimers);
                    fprintf('Cleaned up %d timers.\n', length(allTimers));
                end
            catch
            end
        
            % Close port with retry
            try
                if ~isempty(app.sp) && isvalid(app.sp)
                    delete(app.sp);
                    fprintf('Serial port closed.\n');
                end
            catch ME
                fprintf('Serial close error: %s\n', ME.message);
            end
            app.sp = [];
            
            % Reset the closing flag
            app.isClosing = false;
        
            setConnectionUI(app,'disconnected');
            if isprop(app, 'StatusLabel')
                app.StatusLabel.Text = 'Status: Disconnected';
                app.StatusLabel.FontColor = [1 0.2 0.2]; % Red
            end
            fprintf('Disconnected.\n');
        end
    
        function mainLoop(app)
            % Exit immediately if closing
            if app.isClosing || ~app.isConnected || isempty(app.sp) || ~isvalid(app.sp)
                return;
            end
            
            try
                % Read and process serial data
                readCount = 0;
                maxReads = 100;
                
                while app.sp.NumBytesAvailable > 0 && readCount < maxReads
                    try
                        line = strtrim(readline(app.sp));
                        if ~isempty(line)
                            app.lastRxTime = tic;  % Update last receive time
                            processLine(app, line);
                        end
                        readCount = readCount + 1;
                    catch
                        break;
                    end
                end
                
                % Check for timeout
                if toc(app.lastRxTime) > app.CONNECTION_TIMEOUT
                    fprintf('Connection timeout!\n');
                    forceDisconnect(app, 'timeout');
                    return;
                end
                
                % Safety check
                safetyCheck(app);
                
                % Update GUI every second call (1 Hz update rate)
                app.mainLoopCounter = app.mainLoopCounter + 1;
                if mod(app.mainLoopCounter, 2) == 0
                    updateGUI(app);
                end
                
            catch ME
                fprintf('mainLoop error: %s\n', ME.message);
            end
        end
        
        function processLine(app, line)
            try
                % Check if it's SLCAN format (starts with 't' or 'T')
                if startsWith(line, 't') || startsWith(line, 'T')
                    processSLCANFrame(app, line);
                    return;
                end
                
                % Otherwise process as CSV format
                parts = strsplit(line, ',');
                if numel(parts) < 2
                    return;
                end
                
                typ = parts{1};
                
                switch typ
                    case 'V'
                        if numel(parts) < 4, return; end
                        seg = str2double(parts{2});
                        cell = str2double(parts{3});
                        val = str2double(parts{4});
                        if seg>=1 && seg<=12 && cell>=1 && cell<=12
                            app.VoltageList(seg, cell) = val;
                        end
                        
                    case 'T'
                        if numel(parts) < 4, return; end
                        seg = str2double(parts{2});
                        cell = str2double(parts{3});
                        val = str2double(parts{4});
                        if seg>=1 && seg<=12 && cell>=1 && cell<=12
                            app.TempsList(seg, cell) = val;
                        end
                        
                    case 'IC'
                        if numel(parts) < 3, return; end
                        ic = str2double(parts{2});
                        val = str2double(parts{3});
                        if ic>=1 && ic<=12
                            app.MAXICTemps(ic) = val;
                        end
                        
                    case 'I'
                        if numel(parts) < 4, return; end
                        app.ts_current = str2double(parts{4});
                        
                    case 'IVT'
                        if numel(parts) < 2, return; end
                        app.ivt_current = str2double(parts{2});
                        
                    case 'C'
                        if numel(parts) < 4, return; end
                        app.charging_current = str2double(parts{4});
                        
                    case 'CC'
                        if numel(parts) < 2, return; end
                        app.charging_current = str2double(parts{2});
                        
                    case 'P'
                        if numel(parts) < 4, return; end
                        app.pack_voltage = str2double(parts{4});
                        
                    case 'S'
                        if numel(parts) < 4, return; end
                        app.charger_connected = strcmp(parts{4}, '1');
                        
                    case 'CH'
                        if numel(parts) < 3, return; end
                        app.charger_connected = logical(str2double(parts{2}));
                        app.charger_status_flag = str2double(parts{3});
                        
                    case 'ERR'
                        if numel(parts) < 2, return; end
                        code = str2double(parts{2});
                        switch code
                            case 0, app.lastError = 'ISOSPI';
                            case 1, app.lastError = 'Voltages';
                            case 2, app.lastError = 'Temps';
                            case 3, app.lastError = 'Current Sensor';
                            otherwise, app.lastError = 'Generic';
                        end
                        if isprop(app, 'ErrorLabel')
                            app.ErrorLabel.Text = "Error: " + app.lastError;
                        end
                end
                
            catch ME
                fprintf('processLine error: %s\n', ME.message);
            end
        end
    
        function updateGUI(app)
            try
                % Voltages table
                Vdat = cell(12,12);
                for s = 1:12
                    for c = 1:12
                        v = app.VoltageList(s,c);
                        if v >= 0
                            Vdat{s,c} = sprintf('%.3f V', v);
                        else
                            Vdat{s,c} = '-- V';
                        end
                    end
                end
                app.UITable3.Data = Vdat;
        
                % Temps table - only cells 2,3,4,5,6,7,8,9,10,12
                temp_indices = [2,3,4,5,6,7,8,9,10,12];
                Tdat = cell(12,10);
                for s = 1:12
                    for col_idx = 1:10
                        actual_temp_idx = temp_indices(col_idx);
                        t = app.TempsList(s, actual_temp_idx);
                        if t >= 0
                            Tdat{s,col_idx} = sprintf('%d °C', round(t));
                        else
                            Tdat{s,col_idx} = '-- °C';
                        end
                    end
                end
                app.UITable2.Data = Tdat;
        
                % Styling (optional)
                try
                    removeStyle(app.UITable3);
                    removeStyle(app.UITable2);
        
                    orangeStyle = uistyle('FontColor',[1 0.5 0]);
                    redStyle    = uistyle('FontColor',[1 0   0]);
        
                    % Voltage coloring
                    for s = 1:12
                        vals = app.VoltageList(s,:);
                        valid = vals(vals >= 0);
                        if isempty(valid)
                            avg = NaN;
                        else
                            avg = mean(valid);
                        end
        
                        for c = 1:12
                            v = app.VoltageList(s,c);
                            if v < 0
                                continue;
                            end
        
                            if ~isnan(avg) && abs(v - avg) > 0.1
                                addStyle(app.UITable3, orangeStyle, 'cell', [s c]);
                            end
                            if v < 3.52 || v > 4.09
                                addStyle(app.UITable3, redStyle, 'cell', [s c]);
                            end
                        end
                    end
        
                    % Temp coloring - only for displayed cells
                    for s = 1:12
                        for col_idx = 1:10
                            actual_temp_idx = temp_indices(col_idx);
                            t = app.TempsList(s, actual_temp_idx);
                            if t >= 0 && t < 59
                                addStyle(app.UITable2, redStyle, 'cell', [s col_idx]);
                            end
                        end
                    end
                catch
                    % Styling not supported
                end
                
                % ===== VOLTAGE STATISTICS =====
                voltAll = app.VoltageList(app.VoltageList >= 0);
                
                if isprop(app, 'PackVoltLabel')
                    vDec = 3 + double(app.showExtraPrecision);
                    if ~isempty(voltAll)
                        % Clear waiting message once real data arrives
                        if contains(app.StatusLabel.Text, 'waiting for data')
                            app.StatusLabel.Text = strrep(app.StatusLabel.Text, ' — waiting for data...', '');
                        end
                        packV = 0;
                        for s = 1:12
                            segVals = app.VoltageList(s, app.VoltageList(s,:) >= 0);
                            if ~isempty(segVals)
                                packV = packV + sum(segVals);
                            end
                        end
                        app.PackVoltLabel.Text = sprintf('Pack: %.2f V', packV);
                        
                        minV = min(voltAll);
                        maxV = max(voltAll);
                        avgV = mean(voltAll);
                        deltaV = maxV - minV;
                        
                        app.MinVoltLabel.Text = sprintf('Min: %.*f V', vDec, minV);
                        app.MaxVoltLabel.Text = sprintf('Max: %.*f V', vDec, maxV);
                        app.AvgVoltLabel.Text = sprintf('Avg: %.*f V', vDec, avgV);
                        
                        if app.showExtraPrecision
                            app.DeltaVoltLabel.Text = sprintf('Δ: %.*f V', vDec, deltaV);
                        else
                            app.DeltaVoltLabel.Text = ':)';
                        end
                    else
                        % No data yet — still apply precision toggle to delta label
                        if app.showExtraPrecision
                            app.DeltaVoltLabel.Text = 'Δ: -- V';
                        else
                            app.DeltaVoltLabel.Text = ':)';
                        end
                    end
                end
                % Max Voltage ID
                if isprop(app, 'MaxVIDLabel')
                    validV = app.VoltageList;
                    validV(validV < 0) = NaN;
                    if any(~isnan(validV(:)))
                        [~, idxV] = max(validV(:), [], 'omitnan');
                        [segV, cellV] = ind2sub(size(validV), idxV);
                        app.MaxVIDLabel.Text = sprintf('Max V: IC%d/C%d', segV, cellV);
                    else
                        app.MaxVIDLabel.Text = 'Max V: --';
                    end
                end
                
                % ===== TEMPERATURE STATISTICS =====
                tempAll = app.TempsList(app.TempsList >= -40);
                
                if isprop(app, 'MinTempLabel')
                    tDec = 1 + double(app.showExtraPrecision);
                    if ~isempty(tempAll)
                        app.MinTempLabel.Text = sprintf('MinT: %.*f °C', tDec, min(tempAll));
                        app.MaxTempLabel.Text = sprintf('MaxT: %.*f °C', tDec, max(tempAll));
                    else
                        app.MinTempLabel.Text = 'MinT: -- °C';
                        app.MaxTempLabel.Text = 'MaxT: -- °C';
                    end
                end                
                % Max Temperature ID (only from displayed columns)
                if isprop(app, 'MaxTIDLabel')
                    Tdisp = app.TempsList(:, temp_indices);
                    Tdisp(Tdisp < 0) = NaN;
                    if any(~isnan(Tdisp(:)))
                        [~, idxT] = max(Tdisp(:), [], 'omitnan');
                        [segT, colLocal] = ind2sub(size(Tdisp), idxT);
                        realTcol = temp_indices(colLocal);
                        app.MaxTIDLabel.Text = sprintf('Max T: IC%d/T%d', segT, realTcol);
                    else
                        app.MaxTIDLabel.Text = 'Max T: --';
                    end
                end
                
                % IC Max Temperature
                if isprop(app, 'ICMaxTempLabel')
                    maxICT = max(app.MAXICTemps, [], 'omitnan');
                    if ~isnan(maxICT)
                        app.ICMaxTempLabel.Text = sprintf('IC Max: %.1f °C', maxICT);
                    else
                        app.ICMaxTempLabel.Text = 'IC Max: -- °C';
                    end
                end
                
                % ===== CURRENT TELEMETRY =====
                if isprop(app, 'TSCurrentLabel')
                    cDec = 2 + double(app.showExtraPrecision);
                    if app.ts_current >= 0
                        current = app.ts_current;
                        app.TSCurrentLabel.Text = sprintf('TS: %.*f A', cDec, current);
                        if current > 225.0
                            app.TSCurrentLabel.FontColor = [1 0 0];
                        else
                            app.TSCurrentLabel.FontColor = [0 0 0];
                        end
                    elseif app.ivt_current ~= 0
                        current = app.ivt_current / 1000.0;
                        app.TSCurrentLabel.Text = sprintf('IVT: %.*f A', cDec, current);
                        if current > 225.0
                            app.TSCurrentLabel.FontColor = [1 0 0];
                        else
                            app.TSCurrentLabel.FontColor = [0 0 0];
                        end
                    else
                        app.TSCurrentLabel.Text = 'TS: -- A';
                        app.TSCurrentLabel.FontColor = [0 0 0];
                    end
                end                
                
                
                % ===== CHARGER STATUS =====
                % ===== CHARGER STATUS =====
    if isprop(app, 'ChargerStatusLabel')
        if ~app.isConnected
            chargerTxt = "Charger: --";
        else
            if app.charger_status_flag == -1
                chargerTxt = "Charger: Unknown";
            elseif app.charger_status_flag == 0
                if app.charger_connected
                    chargerTxt = "Charger: OK";
                else
                    chargerTxt = "Charger: Disconnected";
                end
            elseif app.charger_status_flag == 1
                chargerTxt = "Charger: No DC Link";
            else
                chargerTxt = "Charger: Fault";
            end
        end
        % Append charging current if available
        if app.charging_current >= 0
            cDec = 2 + double(app.showExtraPrecision);
            chargerTxt = chargerTxt + sprintf(' | %.*fA', cDec, app.charging_current);
        end
        app.ChargerStatusLabel.Text = chargerTxt;
        
        % Color coding
        if app.charger_connected
            app.ChargerStatusLabel.FontColor = [0 0.6 0];  % Green
        else
            app.ChargerStatusLabel.FontColor = [0 0 0];    % Black
        end
    end
    
    % ===== SEGMENTS SUMMARY TAB =====
    % Calculate segment averages (mean voltage per IC)
    segmentAvgs = zeros(1, 12);
    for s = 1:12
        vals = app.VoltageList(s, :);
        valid = vals(vals >= 0);
        if ~isempty(valid)
            segmentAvgs(s) = mean(valid);
        else
            segmentAvgs(s) = NaN;
        end
    end
    
    % Format segment averages with decimal precision based on precision setting
    segAvgData = cell(1, 12);
    vDec = 3 + double(app.showExtraPrecision);
    for s = 1:12
        if ~isnan(segmentAvgs(s))
            segAvgData{1, s} = sprintf('%.*f V', vDec, segmentAvgs(s));
        else
            segAvgData{1, s} = '-- V';
        end
    end
    app.SegmentAvgTable.Data = segAvgData;
    
    % Format IC temperatures with decimal precision based on precision setting
    icTempData = cell(1, 12);
    tDec = 1 + double(app.showExtraPrecision);
    for ic = 1:12
        temp = app.MAXICTemps(ic);
        if ~isnan(temp)
            icTempData{1, ic} = sprintf('%.*f °C', tDec, temp);
        else
            icTempData{1, ic} = '-- °C';
        end
    end
    app.ICTempTable.Data = icTempData;
    
    % Log CSV row (writes one complete row every GUI update)
    logCSVRow(app);
    
                catch ME
                    fprintf('GUI update error: %s\n', ME.message);
                end
            end
        function applyChargerMode(app)

    % Charger mode comes from checkbox
    app.chargerMode = logical(app.BalanceCheckBox.Value);

    % If charger mode OFF -> force stop and disable controls
    if ~app.chargerMode

        if app.chargingState == 1
            stopCharging(app);
        end

        app.ChargeButton.Enable = 'off';
        app.ChargeCurrentInputEditField.Enable = 'off';
        return;
    end

    % Charger mode ON -> enable controls only if charger OK
    chargerOK = app.isConnected && ...
                app.charger_connected && ...
                app.charger_status_flag == 0;
    if chargerOK
            app.ChargeButton.Enable = 'on';
            app.ChargeCurrentInputEditField.Enable = 'on';
        else
            app.ChargeButton.Enable = 'off';
            app.ChargeCurrentInputEditField.Enable = 'off';
    
            if app.chargingState == 1
                stopCharging(app);
            end
    end
   end
        
        function setConnectionUI(app, state)
            switch lower(state)
                case 'connected'
                    app.Image.ImageSource = app.connectedIcon;
                    app.ConnectButton.Text = 'Disconnect';
                case 'connecting'
                    if exist(app.connectingIcon,'file')
                        app.Image.ImageSource = app.connectingIcon;
                    end
                    app.ConnectButton.Text = 'Connecting...';
                otherwise
                    app.Image.ImageSource = app.disconnectedIcon;
                    app.ConnectButton.Text = 'Connect';
            end
        end

        function processSLCANFrame(app, line)
            try
                s = char(line);
                
                % Parse SLCAN format
                if s(1) == 't'
                    % Standard CAN frame: t<III><L><DD...>
                    canId = hex2dec(s(2:4));
                    dlc = hex2dec(s(5));
                    dataHex = s(6:5+2*dlc);
                elseif s(1) == 'T'
                    % Extended CAN frame: T<IIIIIIII><L><DD...>
                    canId = hex2dec(s(2:9));
                    dlc = hex2dec(s(10));
                    dataHex = s(11:10+2*dlc);
                else
                    return;
                end
                
                % Convert hex data to bytes
                dataBytes = uint8(zeros(1, dlc));
                for i = 1:dlc
                    dataBytes(i) = uint8(hex2dec(dataHex(2*i-1:2*i)));
                end
                
                % Decode CAN frame
                decodeCANFrame(app, canId, dataBytes);
                
            catch ME
                fprintf('SLCAN parse error: %s\n', ME.message);
            end
        end

        function decodeCANFrame(app, canId, data)
            try
                switch canId
                    case hex2dec('700')  % Voltages + charger status
                        if numel(data) < 8, return; end
                        
                        b1 = data(1);
                        b2 = data(2);
                        
                        ic = bitand(b1, 15) + 1;  % IC number (1-12)
                        base = bitand(b2, 15);     % Base cell index (0-11)
                        
                        % Extract 3 voltage values
                        for i = 0:2
                            idx = mod(base + i, 12) + 1;  % MATLAB 1-based
                            
                            hi = uint16(data(3 + 2*i));
                            lo = uint16(data(4 + 2*i));
                            rawU16 = bitor(bitshift(hi, 8), lo);
                            rawI16 = typecast(rawU16, "int16");
                            
                            v = (double(rawI16) + 10000) * 0.000150;
                            app.VoltageList(ic, idx) = v;
                        end
                        
                        % Charger status from high nibble of b2
                        hb = bitshift(b2, -4);
                        app.charger_connected = logical(bitand(hb, 1));
                        app.charger_status_flag = bitand(bitshift(hb, -1), 3);
                        if app.chargerMode
                            chargerOK = app.charger_connected && app.charger_status_flag == 0;

                            if ~chargerOK && app.chargingState == 1
                                stopCharging(app);
                            end
                        end
                    case hex2dec('701')  % Temperatures
                        if numel(data) < 3, return; end
                        
                        b1 = data(1);
                        b2 = data(2);
                        
                        ic = bitand(b1, 15) + 1;
                        base = bitand(b2, 15);
                        
                        % Max IC temp
                        maxIcTemp = bitor(bitshift(bitand(bitshift(b1,-4),15),4), bitshift(b2,-4));
                        app.MAXICTemps(ic) = double(maxIcTemp);
                        
                        % Temperature values (map to correct columns)
                        tempMap = [2 3 4 5 6 7 8 9 10 12];
                        nTemps = numel(data) - 2;
                        
                        for i = 0:(nTemps-1)
                            sidx = mod(base + i, numel(tempMap));
                            col = tempMap(sidx + 1);
                            t = double(data(3 + i)) - 100;
                            app.TempsList(ic, col) = t;
                        end
                        
                    case hex2dec('521')  % IVT Current
                        if numel(data) < 8, return; end
                        raw32 = uint32(data(5))*2^24 + uint32(data(6))*2^16 + ...
                                uint32(data(7))*2^8 + uint32(data(8));
                        app.ivt_current = double(typecast(uint32(raw32), 'int32'));
                        
                    case hex2dec('102')  % Charger Current
                        if numel(data) < 1, return; end
                        app.charging_current = double(data(1)) / 10.0;
                        
                    case hex2dec('101')  % Fault - stop charging
                        if numel(data) < 1, return; end
                        code = double(data(1));
                        switch code
                            case 0, app.lastError = 'ISOSPI';
                            case 1, app.lastError = 'Voltages';
                            case 2, app.lastError = 'Temps';
                            case 3, app.lastError = 'Current Sensor';
                            otherwise, app.lastError = 'Generic';
                        end
                        
                        if isprop(app, 'ErrorLabel')
                            app.ErrorLabel.Text = "Error: " + app.lastError;
                        end
                        
                        % Stop charging if active
                        if app.chargingState == 1
                            stopCharging(app);
                        end
                end
                
            catch ME
                fprintf('CAN decode error: %s\n', ME.message);
            end
        end
    
    end    
    
    methods (Access = public)
        
         % Test method to inject data
        function injectTestData(app)
            fprintf('Injecting test data...\n');
            
            % Set voltage data for all segments
            for seg = 1:12
                for cell = 1:12
                    app.VoltageList(seg, cell) = 3.65 + (seg-1)*0.01 + (rand - 0.5) * 0.05;
                end
            end
            
            % Set temperature data (only cells 2,3,4,5,6,7,8,9,10,12)
            temp_indices = [2,3,4,5,6,7,8,9,10,12];
            for seg = 1:12
                for temp_idx = temp_indices
                    app.TempsList(seg, temp_idx) = 45 + seg + randi(10);
                end
            end
            
            % Set telemetry
            app.ts_current = 150.5;
            app.charging_current = 8.2;
            app.pack_voltage = 44.8;
            app.charger_connected = true;
            
            % Update GUI
            updateGUI(app);
            
            fprintf('Test data injected and GUI updated!\n');
        end

        function startCharging(app)
                   % Charger mode must be enabled
            if ~app.chargerMode
                app.ErrorLabel.Text = 'Error: Charger Mode Disabled';
                app.ErrorLabel.FontColor = [1 0 0];
                app.ChargeButton.Value = 0;
                return;
            end
            
            % Charger must be connected and OK
            if ~app.charger_connected || app.charger_status_flag ~= 0
                app.ErrorLabel.Text = 'Error: Charger Not Ready';
                app.ErrorLabel.FontColor = [1 0 0];
                app.ChargeButton.Value = 0;
                return;
            end
            if ~app.isConnected || isempty(app.sp)
                if isprop(app, 'ErrorLabel')
                    app.ErrorLabel.Text = 'Error: Not connected';
                    app.ErrorLabel.FontColor = [1 0 0];
                end
                if isprop(app, 'ChargeButton')
                    app.ChargeButton.Value = 0;
                end
                return;
            end
            
            % Get current value from input
            if isprop(app, 'ChargeCurrentInputEditField')
                curr = app.ChargeCurrentInputEditField.Value;
            else
                curr = 0;
            end
            
            % STRICT VALIDATION - must be between 0 and 6A
            if isnan(curr) || curr < 0 || curr > 6
                if isprop(app, 'ErrorLabel')
                    app.ErrorLabel.Text = 'Error: Current must be 0-6 A';
                    app.ErrorLabel.FontColor = [1 0 0];
                end
                if isprop(app, 'ChargeButton')
                    app.ChargeButton.Value = 0;
                end
                
                % Reset to valid value
                if isprop(app, 'ChargeCurrentInputEditField')
                    app.ChargeCurrentInputEditField.Value = min(max(curr, 0), 6);
                end
                
                uialert(app.UIFigure, ...
                    'Charge current must be between 0 and 6 Amps.', ...
                    'Invalid Current', 'Icon', 'warning');
                return;
            end
            
            % Calculate hex code for current (same as Python)
            rawVal = int32(curr * 10) + 100;
            app.chargeHexCode = upper(dec2hex(rawVal, 2));
            
            % Get balance flag
            balanceFlag = 0;
            if isprop(app, 'BalanceCheckBox')
                balanceFlag = app.BalanceCheckBox.Value;
            end
            
            % Send charge ON command
            on_off = '0';  % 0 = ON
            cmd = sprintf('T1502%s%d%s', app.chargeHexCode, balanceFlag, on_off);
            
            try
                writeline(app.sp, cmd);
                writeline(app.sp, 'L1');  % SLCAN command confirmation
                
                app.chargingState = 1;
                
                if isprop(app, 'StatusLabel')
                    if curr == 0
                        app.StatusLabel.Text = 'Charging 0 A (float mode)';
                    else
                        app.StatusLabel.Text = sprintf('Charging %.1f A', curr);
                    end
                    app.StatusLabel.FontColor = [0 0.6 0];  % Green
                end
                
                if isprop(app, 'ErrorLabel')
                    app.ErrorLabel.Text = 'Error: None';
                    app.ErrorLabel.FontColor = [0 0 0];
                end
                
                fprintf('Charging started: %.1f A (hex: %s)\n', curr, app.chargeHexCode);
                
            catch ME
                if isprop(app, 'ErrorLabel')
                    app.ErrorLabel.Text = 'Error: ' + string(ME.message);
                    app.ErrorLabel.FontColor = [1 0 0];
                end
                if isprop(app, 'ChargeButton')
                    app.ChargeButton.Value = 0;
                end
                app.chargingState = 0;
                fprintf('Charging start failed: %s\n', ME.message);
            end
        end
        
            function stopCharging(app)
            if isempty(app.sp)
                app.chargingState = 0;
                if isprop(app, 'ChargeButton')
                    app.ChargeButton.Value = 0;
                end
                return;
            end
            
            % Get balance flag
            balanceFlag = 0;
            if isprop(app, 'BalanceCheckBox')
                balanceFlag = app.BalanceCheckBox.Value;
            end
            
            % Send charge OFF command
            on_off = '1';  % 1 = OFF
            cmd = sprintf('T1502%s%d%s', app.chargeHexCode, balanceFlag, on_off);
            
            try
                app.chargingState = 0;
                writeline(app.sp, cmd);
                writeline(app.sp, 'L1');
                
                if isprop(app, 'StatusLabel')
                    app.StatusLabel.Text = 'Charging OFF';
                    app.StatusLabel.FontColor = [1 0.6 0];  % Orange
                end
                
                if isprop(app, 'ChargeButton')
                    app.ChargeButton.Value = 0;
                end
                
                fprintf('Charging stopped\n');
                
            catch ME
                if isprop(app, 'ErrorLabel')
                    app.ErrorLabel.Text = 'Error: ' + string(ME.message);
                end
            end
        end

        function safetyCheck(app)
            if ~app.isConnected
                return;
            end
            
            voltAll = app.VoltageList(app.VoltageList >= 0);
            tempAll = app.TempsList(app.TempsList >= -40);
            
            overV = ~isempty(voltAll) && max(voltAll) > app.MAX_VOLTAGE_THRESHOLD;
            underV = ~isempty(voltAll) && min(voltAll) < app.MIN_VOLTAGE_THRESHOLD;
            overT = ~isempty(tempAll) && max(tempAll) > app.TEMP_WARN_LIMIT;
            
            % Only stop if currently charging
            if app.chargingState == 1 && (overV || underV || overT)
                stopCharging(app);
                
                if overV
                    reason = "Over-voltage";
                elseif underV
                    reason = "Under-voltage";
                else
                    reason = "Over-temperature";
                end
                
                if isprop(app, 'ErrorLabel')
                    app.ErrorLabel.Text = "SAFETY: " + reason;
                    app.ErrorLabel.FontColor = [1 0 0];  % Red
                end
                
                if isprop(app, 'StatusLabel')
                    app.StatusLabel.Text = "STOPPED: " + reason;
                    app.StatusLabel.FontColor = [1 0 0];  % Red
                end
                
                fprintf('SAFETY STOP: %s\n', reason);
            end
        end

        function sendHeartbeat(app)
            if ~app.isConnected || isempty(app.sp)
                return;
            end
            
            try
                % Get balance flag
                balanceFlag = 0;
                if isprop(app, 'BalanceCheckBox')
                    balanceFlag = app.BalanceCheckBox.Value;
                end
                
                % Determine on/off based on charging state
                on_off = '1';  % Default OFF
                if app.chargingState == 1
                    on_off = '0';  % ON
                end
                
                % Send heartbeat command
                hb = sprintf('T1502%s%d%s', app.chargeHexCode, balanceFlag, on_off);
                writeline(app.sp, hb);
                writeline(app.sp, 'L1');

                % inside sendHeartbeat(app):
                if app.wifiEnabled
                    wifiSend(app, hb + newline);
                end

                
            catch
                % Ignore heartbeat errors
            end
        end

        function forceDisconnect(app, reason)
            fprintf('Force disconnect: %s\n', reason);
            
            app.isConnected = false;
            app.isClosing = true;
            
            % Stop all timers
            try
                if ~isempty(app.MainTimer) && isvalid(app.MainTimer)
                    stop(app.MainTimer);
                end
            catch
            end
            
            try
                if ~isempty(app.heartbeatTimer) && isvalid(app.heartbeatTimer)
                    stop(app.heartbeatTimer);
                end
            catch
            end
            
            % Close serial
            try
                if ~isempty(app.sp) && isvalid(app.sp)
                    delete(app.sp);
                end
            catch
            end
            app.sp = [];
            
            % Update UI
            setConnectionUI(app, 'disconnected');
            
            if isprop(app, 'StatusLabel')
                app.StatusLabel.Text = sprintf('Disconnected (%s)', reason);
                app.StatusLabel.FontColor = [1 0.2 0.2];
            end
            
            if isprop(app, 'ChargeButton')
                app.ChargeButton.Value = 0;
            end
            
            if isprop(app, 'ConnectButton')
                app.ConnectButton.Text = 'Connect';
            end
            
            app.chargingState = 0;
            app.isClosing = false;
        end

        function openCSVLog(app)
            if app.logFID >= 0
                return; % Already open
            end
            
            path = fullfile(pwd, app.logFile);
            newFile = ~isfile(path);
            
            [fid, msg] = fopen(path, "a");
            if fid < 0
                if isprop(app, 'ErrorLabel')
                    app.ErrorLabel.Text = "CSV Error: " + string(msg);
                end
                app.LogtoCSVCheckBox.Value = 0;
                app.logEnabled = false;
                return;
            end
            
            app.logFID = fid;
            
            % Write header if new file
            if newFile
                writeCSVHeader(app);
                app.csvHeaderWritten = true;
            end
        end
        
        function writeCSVHeader(app)
            % Write comprehensive header
            fprintf(app.logFID, 'timestamp,packV,minV,maxV,avgV,deltaV,minT,maxT,ivtA,chargerA,chargerConn,chargerStatus,lastError');
            
            % Voltage columns (12 ICs × 12 cells = 144 columns)
            for ic = 1:12
                for c = 1:12
                    fprintf(app.logFID, ',V_%d_%d', ic, c);
                end
            end
            
            % Temperature columns (12 ICs × 10 displayed temps = 120 columns)
            temp_indices = [2,3,4,5,6,7,8,9,10,12];
            for ic = 1:12
                for t = temp_indices
                    fprintf(app.logFID, ',T_%d_%d', ic, t);
                end
            end
            
            % IC temperature columns (12 ICs)
            for ic = 1:12
                fprintf(app.logFID, ',ICtemp_%d', ic);
            end
            
            fprintf(app.logFID, '\n');
        end
        
        function closeCSVLog(app)
            try
                if app.logFID >= 0
                    fclose(app.logFID);
                end
            catch
            end
            app.logFID = -1;
        end
        
        function logCSVRow(app)
            if ~app.logEnabled || app.logFID < 0
                return;
            end
            
            try
                % Calculate statistics
                voltAll = app.VoltageList(app.VoltageList >= 0);
                tempAll = app.TempsList(app.TempsList >= -40);
                
                packV = NaN; minV = NaN; maxV = NaN; avgV = NaN; deltaV = NaN;
                minT = NaN; maxT = NaN;
                
                
                    if ~isempty(voltAll)
                        packV = 0;
                        for s = 1:12
                        segVals = app.VoltageList(s, app.VoltageList(s,:) >= 0);
                        if ~isempty(segVals)
                            packV = packV + sum(segVals);
                        end
                 end
                    
                    minV = min(voltAll);
                    maxV = max(voltAll);
                    avgV = mean(voltAll);
                    deltaV = maxV - minV;
                end
                
                if ~isempty(tempAll)
                    minT = min(tempAll);
                    maxT = max(tempAll);
                end
                
                ivtA = app.ivt_current / 1000.0;  % Convert mA to A
                chgA = app.charging_current;
                t = toc(app.t0);
                
                % Write summary columns
                fprintf(app.logFID, ...
                    '%.3f,%.3f,%.3f,%.3f,%.3f,%.3f,%.1f,%.1f,%.3f,%.3f,%d,%d,%s', ...
                    t, packV, minV, maxV, avgV, deltaV, ...
                    minT, maxT, ivtA, chgA, ...
                    app.charger_connected, app.charger_status_flag, ...
                    string(app.lastError));
                
                % Write all voltage values (12×12 = 144 values)
                for ic = 1:12
                    for c = 1:12
                        v = app.VoltageList(ic, c);
                        if v >= 0
                            fprintf(app.logFID, ',%.4f', v);
                        else
                            fprintf(app.logFID, ',NaN');
                        end
                    end
                end
                
                % Write temperature values (12×10 = 120 values)
               temp_indices = [2,3,4,5,6,7,8,9,10,12];
                for ic = 1:12
                    for tidx = temp_indices
                        temp = app.TempsList(ic, tidx);
                        if temp >= -40
                            fprintf(app.logFID, ',%.2f', temp);
                        else
                            fprintf(app.logFID, ',NaN');
                        end
                    end
                end
                
                % Write IC temperatures (12 values)
                for ic = 1:12
                    ict = app.MAXICTemps(ic);
                    if ~isnan(ict)
                        fprintf(app.logFID, ',%.1f', ict);
                    else
                        fprintf(app.logFID, ',NaN');
                    end
                end
                
                fprintf(app.logFID, '\n');
                
            catch ME
                fprintf('CSV logging error: %s\n', ME.message);
            end
        end

        function connectWifi(app)
            % If already open, do nothing
            if app.wifiEnabled && ~isempty(app.udpRx)
                return;
            end
        
            try
                % Close any previous UDP object
                try
                    if ~isempty(app.udpRx)
                        delete(app.udpRx);
                        app.udpRx = [];
                    end
                catch
                end
                app.udpRx = [];
        
                % Open UDP listener
                app.udpRx = udpport("datagram", "IPV4", "LocalPort", app.wifiLocalPort);
                app.wifiEnabled = true;
        
                % Start WiFi receive timer (fast enough to feel realtime, not too heavy)
                if ~isempty(app.wifiTimer) && isvalid(app.wifiTimer)
                    stop(app.wifiTimer);
                    delete(app.wifiTimer);
                end
        
                app.wifiTimer = timer( ...
                    'ExecutionMode', 'fixedRate', ...
                    'Period', 0.05, ...           % 20 Hz receive polling
                    'TimerFcn', @(~,~)wifiLoop(app));
                start(app.wifiTimer);
        
                % Update UI
                setConnectionUI(app, 'connected');
                if isprop(app,'StatusLabel')
                    app.StatusLabel.Text = sprintf('WiFi UDP: listening on %d', app.wifiLocalPort);
                    app.StatusLabel.FontColor = [0 0.6 0];
                end
                if isprop(app,'ErrorLabel')
                    app.ErrorLabel.Text = 'Error: None';
                    app.ErrorLabel.FontColor = [0 0 0];
                end
        
                % Mark "connected" for logic that expects it
                app.isConnected = true;
                app.lastRxTime = tic;
        
            catch ME
                app.wifiEnabled = false;
                app.isConnected = false;
        
                try
                    if ~isempty(app.wifiTimer) && isvalid(app.wifiTimer)
                        stop(app.wifiTimer); delete(app.wifiTimer);
                    end
                catch
                end
        
                try
                    if ~isempty(app.udpRx)
                        delete(app.udpRx);
                        app.udpRx = [];
                    end
                catch
                end
                app.udpRx = [];
        
                setConnectionUI(app, 'disconnected');
                if isprop(app,'StatusLabel')
                    app.StatusLabel.Text = 'WiFi Connection Failed';
                    app.StatusLabel.FontColor = [1 0.2 0.2];
                end
                if isprop(app,'ErrorLabel')
                    app.ErrorLabel.Text = "Error: " + string(ME.message);
                    app.ErrorLabel.FontColor = [1 0 0];
                end
        
                uialert(app.UIFigure, sprintf('WiFi UDP open failed: %s', ME.message), 'WiFi Error');
            end
        end
        
        
        function disconnectWifi(app)
            app.wifiEnabled = false;
            app.isConnected = false;
        
            % Stop timer
            try
                if ~isempty(app.wifiTimer) && isvalid(app.wifiTimer)
                    stop(app.wifiTimer);
                    delete(app.wifiTimer);
                end
            catch
            end
            app.wifiTimer = [];
        
            % Close UDP
            try
                if ~isempty(app.udpRx)
                    delete(app.udpRx);
                    app.udpRx = [];
                end
            catch
            end
            app.udpRx = [];
        
            setConnectionUI(app, 'disconnected');
            if isprop(app,'StatusLabel')
                app.StatusLabel.Text = 'Status: WiFi Disconnected';
                app.StatusLabel.FontColor = [1 0.2 0.2];
            end
        end
        
        
        function wifiLoop(app)
            % Similar role to mainLoop(), but for UDP
            if app.isClosing || ~app.wifiEnabled || isempty(app.udpRx)
                return;
            end
        
            try
                readCount = 0;
                maxReads = 200;
        
                % Read all available datagrams
                while app.udpRx.NumDatagramsAvailable > 0 && readCount < maxReads
                    d = read(app.udpRx, 1, "string");     % returns table
                    if isempty(d) || height(d) < 1
                        break;
                    end
        
                    payload = string(d.Data(1));
                    payload = strtrim(payload);
        
                    if payload ~= ""
                        app.lastRxTime = tic;
        
                        % You might receive multiple lines in one UDP packet
                        lines = splitlines(payload);
                        for k = 1:numel(lines)
                            line = strtrim(lines(k));
                            if line ~= ""
                                processLine(app, char(line));  % reuse existing parser
                            end
                        end
                    end
        
                    readCount = readCount + 1;
                end
        
                % Timeout detection (optional but nice)
                if toc(app.lastRxTime) > app.CONNECTION_TIMEOUT
                    forceDisconnect(app, 'wifi timeout');
                    % forceDisconnect closes serial; we also close wifi here:
                    disconnectWifi(app);
                    return;
                end
        
                safetyCheck(app);
        
                % Update GUI at ~10 Hz (every few calls)
                app.wifiLoopCounter = app.wifiLoopCounter + 1;
                if mod(app.wifiLoopCounter, 2) == 0
                    updateGUI(app);
                end
        
            catch ME
                % Keep it quiet-ish but visible
                fprintf('wifiLoop error: %s\n', ME.message);
            end
        end

        function wifiSend(app, msg)
            if ~app.wifiEnabled || isempty(app.udpRx)
                return;
            end
            try
                write(app.udpRx, uint8(msg), app.wifiRemoteIP, app.wifiRemotePort);
            catch
            end
        end


    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % CLEANUP FIRST
            try
                existingTimers = timerfindall;
                if ~isempty(existingTimers)
                    stop(existingTimers);
                    delete(existingTimers);
                    fprintf('Cleaned up %d leftover timers\n', length(existingTimers));
                end
            catch
            end
            
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.connectedIcon    = fullfile(pathToMLAPP,'connected.png');
            app.disconnectedIcon = fullfile(pathToMLAPP,'disconnected.png');
            app.connectingIcon   = fullfile(pathToMLAPP,'connecting.png');
        
            initTables(app);
            
            % Initialize DeltaVoltLabel with smiley
            if isprop(app, 'DeltaVoltLabel')
                app.DeltaVoltLabel.Text = ':)';
            end
            
            % Initialize timestamp base
            app.t0 = tic;
            app.lastRxTime = tic;
            
            fprintf('App started.\n');
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            port = upper(strtrim(app.PortEditField.Value));

            if app.isConnected
                % Disconnect whichever is active
                disconnectPort(app);
                disconnectWifi(app);
                return;
            end
        
            if port == "WIFI"
                connectWifi(app);
            else
                connectPort(app);
            end

        end

        % Value changed function: LogtoCSVCheckBox
        function LogtoCSVCheckBoxValueChanged(app, event)
            app.logEnabled = app.LogtoCSVCheckBox.Value;

            if app.logEnabled
                openCSVLog(app);
                if app.logFID < 0
                    % Failed to open
                    app.LogtoCSVCheckBox.Value = false;
                    app.logEnabled = false;
                else
                    if isprop(app, 'StatusLabel')
                        app.StatusLabel.Text = app.StatusLabel.Text + " [Logging]";
                    end
                end
            else
                closeCSVLog(app);
                if isprop(app, 'StatusLabel')
                    app.StatusLabel.Text = strrep(app.StatusLabel.Text, ' [Logging]', '');
                end
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            fprintf('Closing app...\n');
    
            % Set closing flag FIRST to stop all callbacks
            app.isClosing = true;
            app.isConnected = false;
            
            % Wait a moment for any running callbacks to exit
            pause(0.3);
            
            % Stop and delete timers
            try
                if ~isempty(app.MainTimer) && isvalid(app.MainTimer)
                    stop(app.MainTimer);
                    delete(app.MainTimer);
                end
                fprintf('Timer stopped.\n');
            catch ME
                fprintf('Timer error: %s\n', ME.message);
            end
            
            % Also delete ALL timers as backup
            try
                allTimers = timerfindall;
                if ~isempty(allTimers)
                    stop(allTimers);
                    delete(allTimers);
                end
            catch
            end

            % Stop WiFi timer + close UDP
            try
                if ~isempty(app.wifiTimer) && isvalid(app.wifiTimer)
                    stop(app.wifiTimer);
                    delete(app.wifiTimer);
                end
            catch
            end
            app.wifiTimer = [];
            
            try
                if ~isempty(app.udpRx)
                    delete(app.udpRx);
                    app.udpRx = [];
                end
            catch
            end
            app.udpRx = [];
            app.wifiEnabled = false;
            
            % Close serial
            try
                if ~isempty(app.sp) && isvalid(app.sp)
                    delete(app.sp);
                    fprintf('Serial closed.\n');
                end
            catch ME
                fprintf('Serial error: %s\n', ME.message);
            end
            app.sp = [];
            
            % Close log
            try
                if app.logFID > 0
                    fclose(app.logFID);
                end
            catch
            end
            
            fprintf('Deleting figure...\n');
            delete(app.UIFigure);
            fprintf('App closed.\n');
        end

        % Size changed function: UIFigure
        function UIFigureSizeChanged(app, event)
            %forceLayout(app);
            
        end

        % Value changed function: ChargeButton
        function ChargeButtonValueChanged(app, event)
            if app.ChargeButton.Value == 1
                startCharging(app);
            else
                stopCharging(app);
            end
            
        end

        % Value changed function: PrecisionCheckBox
        function PrecisionCheckBoxValueChanged(app, event)
            app.showExtraPrecision = app.PrecisionCheckBox.Value;
    
            % Update DeltaVoltLabel immediately
            if isprop(app, 'DeltaVoltLabel')
              if app.showExtraPrecision
                app.DeltaVoltLabel.Visible = 'on';
                app.DeltaVoltLabel.Text = 'Δ: -- V';
            else
                app.DeltaVoltLabel.Text = ':)';
             end
           end
            
            % Refresh GUI
            updateGUI(app);
            
        end

        % Value changed function: ChargeCurrentInputEditField
        function ChargeCurrentInputEditFieldValueChanged(app, event)
            value = app.ChargeCurrentInputEditField.Value;
    
            % Clamp to valid range
            if value < 0
                app.ChargeCurrentInputEditField.Value = 0;
            elseif value > 6
                app.ChargeCurrentInputEditField.Value = 6;
                uialert(app.UIFigure, ...
                    'Maximum charge current is 6 Amps.', ...
                    'Maximum Current Reached', 'Icon', 'warning');
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @UIFigureSizeChanged, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.LiveTempsandVoltagesTab = uitab(app.TabGroup);
            app.LiveTempsandVoltagesTab.Title = 'Live Temps and Voltages';
            app.GridLayout = uigridlayout(app.LiveTempsandVoltagesTab);
            app.TemepraturesPanel = uipanel(app.GridLayout);
            app.TemepraturesPanel.Title = 'Temepratures';
            app.UITable2 = uitable(app.TemepraturesPanel);
            app.UITable2.ColumnName = {'T2'; 'T3'; 'T4'; 'T5'; 'T6'; 'T7'; 'T8'; 'T9'; 'T10'; 'T12'};
            app.UITable2.RowName = {'1,2,3,4,5,6,7,8,9,10,11,12'};
            app.UITable2.Tag = 'TempsTable';
            app.VoltagesPanel = uipanel(app.GridLayout);
            app.VoltagesPanel.Title = 'Voltages';
            app.UITable3 = uitable(app.VoltagesPanel);
            app.UITable3.ColumnName = {'V1'; 'V2'; 'V3'; 'V4'; 'V5'; 'V6'; 'V7'; 'V8'; 'V9'; 'V10'; 'V11'; 'V12'};
            app.UITable3.RowName = {};
            app.UITable3.Tag = 'VoltsTable';
            app.ControlsPanel = uipanel(app.GridLayout);
            app.ControlsPanel.Title = 'Controls';
            app.ControlsPanel.Tag = 'Controls';
            app.PortEditFieldLabel = uilabel(app.ControlsPanel);
            app.PortEditFieldLabel.Text = 'Port';
            app.PortEditField = uieditfield(app.ControlsPanel, 'text');
            app.PortEditField.Tag = 'PortEditField';
            app.PortEditField.Value = 'COM3';
            app.ConnectButton = uibutton(app.ControlsPanel, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Tag = 'ConnectStateButton';
            app.ConnectButton.Text = 'Connect';
            app.LogtoCSVCheckBox = uicheckbox(app.ControlsPanel);
            app.LogtoCSVCheckBox.ValueChangedFcn = createCallbackFcn(app, @LogtoCSVCheckBoxValueChanged, true);
            app.LogtoCSVCheckBox.Text = 'Log to CSV';
            app.Image = uiimage(app.ControlsPanel);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'disconnected.png');
            app.BaudRateDropDownLabel = uilabel(app.ControlsPanel);
            app.BaudRateDropDownLabel.Text = 'BaudRate';
            app.BaudRateDropDown = uidropdown(app.ControlsPanel);
            app.BaudRateDropDown.Items = {'125kbit/s', '250kbit/s', '500kbit/s', '1000kbit/s'};
            app.BaudRateDropDown.Value = '500kbit/s';
            app.ChargeCurrentInputEditFieldLabel = uilabel(app.ControlsPanel);
            app.ChargeCurrentInputEditFieldLabel.Text = 'ChargeCurrentInput';
            app.ChargeCurrentInputEditField = uieditfield(app.ControlsPanel, 'numeric');
            app.ChargeCurrentInputEditField.ValueChangedFcn = createCallbackFcn(app, @ChargeCurrentInputEditFieldValueChanged, true);
            app.ChargerStatusLabel = uilabel(app.ControlsPanel);
            app.ChargerStatusLabel.Text = 'ChargerStatusLabel';
            app.ChargeButton = uibutton(app.ControlsPanel, 'state');
            app.ChargeButton.ValueChangedFcn = createCallbackFcn(app, @ChargeButtonValueChanged, true);
            app.ChargeButton.Text = 'Charge';
            app.BalanceCheckBox = uicheckbox(app.ControlsPanel);
            app.BalanceCheckBox.Text = 'Balance';
            app.StatusLabel = uilabel(app.ControlsPanel);
            app.StatusLabel.Text = 'StatusLabel';
            app.PackVoltLabel = uilabel(app.ControlsPanel);
            app.PackVoltLabel.Text = 'PackVoltLabel';
            app.MinVoltLabel = uilabel(app.ControlsPanel);
            app.MinVoltLabel.Text = 'MinVoltLabel';
            app.MaxVoltLabel = uilabel(app.ControlsPanel);
            app.MaxVoltLabel.Text = 'MaxVoltLabel';
            app.AvgVoltLabel = uilabel(app.ControlsPanel);
            app.AvgVoltLabel.Text = 'AvgVoltLabel';
            app.DeltaVoltLabel = uilabel(app.ControlsPanel);
            app.DeltaVoltLabel.Text = 'DeltaVoltLabel';
            app.MinTempLabel = uilabel(app.ControlsPanel);
            app.MinTempLabel.Text = 'MinTempLabel';
            app.MaxTempLabel = uilabel(app.ControlsPanel);
            app.MaxTempLabel.Text = 'MaxTempLabel';
            app.TSCurrentLabel = uilabel(app.ControlsPanel);
            app.TSCurrentLabel.Text = 'TSCurrentLabel';
            app.MaxVIDLabel = uilabel(app.ControlsPanel);
            app.MaxVIDLabel.Text = 'MaxVIDLabel';
            app.ErrorLabel = uilabel(app.ControlsPanel);
            app.ErrorLabel.Text = 'ErrorLabel';
            app.MaxTIDLabel = uilabel(app.ControlsPanel);
            app.MaxTIDLabel.Text = 'MaxTIDLabel';
            app.ICMaxTempLabel = uilabel(app.ControlsPanel);
            app.ICMaxTempLabel.Text = 'ICMaxTempLabel';
            app.PrecisionCheckBox = uicheckbox(app.ControlsPanel);
            app.PrecisionCheckBox.ValueChangedFcn = createCallbackFcn(app, @PrecisionCheckBoxValueChanged, true);
            app.PrecisionCheckBox.Text = 'Precision';
            app.SecondaryTab = uitab(app.TabGroup);
            app.SecondaryTab.Title = 'Secondary';
            app.SummaryPanel = uipanel(app.SecondaryTab);
            app.SummaryPanel.Title = 'Summary';
            app.ICTempTable = uitable(app.SummaryPanel);
            app.ICTempTable.ColumnName = {'IC1'; 'IC2'; 'IC3'; 'IC4'; 'IC5'; 'IC6'; 'IC7'; 'IC8'; 'IC9'; 'IC10'; 'IC11'; 'IC12'};
            app.ICTempTable.RowName = {};
            app.SegmentAvgTable = uitable(app.SummaryPanel);
            app.SegmentAvgTable.ColumnName = {'Seg1'; 'Seg2'; 'Seg3'; 'Seg4'; 'Seg5'; 'Seg6'; 'Seg7'; 'Seg8'; 'Seg9'; 'Seg10'; 'Seg11'; 'Seg12'};
            app.SegmentAvgTable.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Revamp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end